classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main1                   matlab.ui.Figure
        Label                   matlab.ui.control.Label
        CONTROLLABILLITYButton  matlab.ui.control.Button
        IDENTIFIABILITYButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        oldpath % Description
        SGoldd % Description
        NLContapp % Description
        rutafinal % Description
    end
    
    methods (Access = private)
        
        %%% DIALOG BOX TO CLOSE THE APP
        function mycallback(app,src,event)
            if event.SelectedOption == "OK"
                %delete('options_aux.m')
                delete(app.SGoldd);
                delete(app.NLContapp);
                delete(app.Main1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.oldpath = pwd;            
            
            app.rutafinal = app.oldpath(end-12:end);
            comparar = strcmp(app.rutafinal,'\STRIKE-GOLDD');
            
            optsMes = struct('WindowStyle','modal',... 
              'Interpreter','tex');
            % if comparar == 0
            %     uiwait(warndlg([ '\fontsize{10} You should run the app from \it STRIKE-GOLDD \rm folder. Press OK ' ...
            %         'to close the APP and to change the Current folder'],'Error in path',optsMes));
            % 
            %     delete(app.SG);
            %     return
            % end
            
            
            addpath(genpath('functions'));
            
            app.Label.Text = sprintf('Which type of analysis do you want\n to run with STRIKE GOLDD?');
            app.CONTROLLABILLITYButton.Text = sprintf('CONTROLLABILITY \n AND ACCESSIBILITY');
            app.IDENTIFIABILITYButton.Text = sprintf('IDENTIFIABILITY \n AND OBSERVABILITY');


        end

        % Button pushed function: CONTROLLABILLITYButton
        function CONTROLLABILLITYButtonPushed(app, event)
            %nombreDeLaClaseApp = 'NLControllability_APP';
            rutaApp = fullfile(app.oldpath, 'functions', 'NLControllability_APP.mlapp');
            %rutaApp = 'C:\Users\xabor\Documents\GitHub\strike-goldd\STRIKE-GOLDD\functions\NLControllability_APP.mlapp';
            if ~contains(path, fileparts(rutaApp))
                addpath(fileparts(rutaApp));
            end
            app.NLContapp = NLControllability_APP;
            %eval(nombreDeLaClaseApp);
        end

        % Button pushed function: IDENTIFIABILITYButton
        function IDENTIFIABILITYButtonPushed(app, event)
            %nombreDeLaClaseApp = 'STRIKE_GOLDD_APP';
            rutaApp = fullfile(app.oldpath, 'functions', 'STRIKE_GOLDD_APP.mlapp');
            %rutaApp = 'C:\Users\xabor\Documents\GitHub\strike-goldd\STRIKE-GOLDD\functions\STRIKE_GOLDD_APP.mlapp';
            if ~contains(path, fileparts(rutaApp))
                addpath(fileparts(rutaApp));
            end

            app.SGoldd = STRIKE_GOLDD_APP;
            %eval(nombreDeLaClaseApp);
        end

        % Close request function: Main1
        function Main1CloseRequest(app, event)
            uiconfirm(app.Main1,'Are you sure you wanto to close the app?','Confirm Close', ...
            'CloseFcn',@app.mycallback);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main1 = uifigure('Visible', 'off');
            app.Main1.Name = 'MATLAB App';
            app.Main1.CloseRequestFcn = createCallbackFcn(app, @Main1CloseRequest, true);
            app.IDENTIFIABILITYButton = uibutton(app.Main1, 'push');
            app.IDENTIFIABILITYButton.ButtonPushedFcn = createCallbackFcn(app, @IDENTIFIABILITYButtonPushed, true);
            app.IDENTIFIABILITYButton.Text = 'IDENTIFIABILITY';
            app.CONTROLLABILLITYButton = uibutton(app.Main1, 'push');
            app.CONTROLLABILLITYButton.ButtonPushedFcn = createCallbackFcn(app, @CONTROLLABILLITYButtonPushed, true);
            app.CONTROLLABILLITYButton.Text = 'CONTROLLABILLITY';
            app.Label = uilabel(app.Main1);
            % Show the figure after all components are created
            app.Main1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main1)
        end
    end
end