classdef NLContApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NLCont     matlab.ui.Figure
        RUNButton  matlab.ui.control.Button
        DoyouwanttocheckSussmannsGeneralSufficientConditionCheckBox  matlab.ui.control.CheckBox
        DoyouwanttochecktheLieAlgebraicRankConditionCheckBox  matlab.ui.control.CheckBox
        DoyouwanttochecktheAccesibilityRankConditionCheckBox  matlab.ui.control.CheckBox
        ifyouwanttochecktheLinearizationConditionCheckBox  matlab.ui.control.CheckBox
        CONTROLLABILITYANDACCESSIBILITYLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        LinearizationYes % Description
    end
    
    methods (Access = private)
        %%% DIALOG BOX TO CLOSE THE APP
        function mycallback(app,src,event)
            if event.SelectedOption == "OK"
                %delete('options_aux.m')
                delete(app.NLCont);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('models'));
            addpath(genpath('functions'));
        end

        % Close request function: NLCont
        function NLContCloseRequest(app, event)
            uiconfirm(app.NLCont,'Are you sure you wanto to close STRIKE-GOLDD?','Confirm Close', ...
            'CloseFcn',@app.mycallback);
        end

        % Value changed function: 
        % ifyouwanttochecktheLinearizationConditionCheckBox
        function LinearizationConditionCheckBoxValueChanged(app, event)
            %%% by default
            % app.ifyouwanttochecktheLinearizationConditionCheckBox.Value = 1            
            
            app.LinearizationYes = app.ifyouwanttochecktheLinearizationConditionCheckBox.Value;
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            %Ensure values are in the properties:
            app.LinearizationYes = app.ifyouwanttochecktheLinearizationConditionCheckBox.Value;
            app.ARC = app.DoyouwanttochecktheAccesibilityRankConditionCheckBox.Value;
            app.LieARC = app.DoyouwanttochecktheLieAlgebraicRankConditionCheckBox.Value;


        end

        % Value changed function: 
        % DoyouwanttochecktheAccesibilityRankConditionCheckBox
        function DoyouwanttochecktheAccesibilityRankConditionCheckBoxValueChanged(app, event)
            %%% by default
            % app.DoyouwanttochecktheAccesibilityRankConditionCheckBox.Value = 1            
            
            app.ARC = app.DoyouwanttochecktheAccesibilityRankConditionCheckBox.Value;
            
        end

        % Value changed function: 
        % DoyouwanttochecktheLieAlgebraicRankConditionCheckBox
        function DoyouwanttochecktheLieAlgebraicRankConditionCheckBoxValueChanged(app, event)
            %%% by default
            % app.DoyouwanttochecktheLieAlgebraicRankConditionCheckBox.Value = 1            

            app.LieARC = app.DoyouwanttochecktheLieAlgebraicRankConditionCheckBox.Value;
            
        end

        % Value changed function: 
        % DoyouwanttocheckSussmannsGeneralSufficientConditionCheckBox
        function DoyouwanttocheckSussmannsGeneralSufficientConditionCheckBoxValueChanged(app, event)
            %%% by default
            % app.DoyouwanttocheckSussmannsGeneralSufficientConditionCheckBox.Value = 1            
            
            app.sussmann = app.DoyouwanttocheckSussmannsGeneralSufficientConditionCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NLCont = uifigure('Visible', 'off');
            app.NLCont.Name = 'MATLAB App';
            app.NLCont.CloseRequestFcn = createCallbackFcn(app, @NLContCloseRequest, true);
            app.CONTROLLABILITYANDACCESSIBILITYLabel = uilabel(app.NLCont);
            app.CONTROLLABILITYANDACCESSIBILITYLabel.Text = 'CONTROLLABILITY AND ACCESSIBILITY';
            app.ifyouwanttochecktheLinearizationConditionCheckBox = uicheckbox(app.NLCont);
            app.ifyouwanttochecktheLinearizationConditionCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinearizationConditionCheckBoxValueChanged, true);
            app.ifyouwanttochecktheLinearizationConditionCheckBox.Tag = 'lineariz';
            app.ifyouwanttochecktheLinearizationConditionCheckBox.Text = 'Do you want to check the linearization condition for your system or model?';
            app.ifyouwanttochecktheLinearizationConditionCheckBox.Value = true;
            app.DoyouwanttochecktheAccesibilityRankConditionCheckBox = uicheckbox(app.NLCont);
            app.DoyouwanttochecktheAccesibilityRankConditionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoyouwanttochecktheAccesibilityRankConditionCheckBoxValueChanged, true);
            app.DoyouwanttochecktheAccesibilityRankConditionCheckBox.Text = 'Do you want to check the Accesibility Rank Condition?';
            app.DoyouwanttochecktheAccesibilityRankConditionCheckBox.Value = true;
            app.DoyouwanttochecktheLieAlgebraicRankConditionCheckBox = uicheckbox(app.NLCont);
            app.DoyouwanttochecktheLieAlgebraicRankConditionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoyouwanttochecktheLieAlgebraicRankConditionCheckBoxValueChanged, true);
            app.DoyouwanttochecktheLieAlgebraicRankConditionCheckBox.Text = 'Do you want to check the Lie Algebraic Rank Condition?';
            app.DoyouwanttochecktheLieAlgebraicRankConditionCheckBox.Value = true;
            app.DoyouwanttocheckSussmannsGeneralSufficientConditionCheckBox = uicheckbox(app.NLCont);
            app.DoyouwanttocheckSussmannsGeneralSufficientConditionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoyouwanttocheckSussmannsGeneralSufficientConditionCheckBoxValueChanged, true);
            app.DoyouwanttocheckSussmannsGeneralSufficientConditionCheckBox.Text = 'Do you want to check Sussmann''s General Sufficient Condition?';
            app.RUNButton = uibutton(app.NLCont, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.NLCont.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLContApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NLCont)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NLCont)
        end
    end
end