classdef STRIKE_GOLDD_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SG                             matlab.ui.Figure
        ModelnameEditFieldLabel_2      matlab.ui.control.Label
        ModelnameDropDown              matlab.ui.control.DropDown
        ButtonGroup                    matlab.ui.container.ButtonGroup
        CREATENEWMODELButton           matlab.ui.control.ToggleButton
        USEEXISTINGMODELButton         matlab.ui.control.ToggleButton
        SaveoptionsfileButton          matlab.ui.control.Button
        CloseappButton                 matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        RunButton                      matlab.ui.control.Button
        AlgorithmDropDown              matlab.ui.control.DropDown
        AlgorithmDropDownLabel         matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        IDENTIFIABILITYOPTIONSTab      matlab.ui.container.Tab
        nonzeroDerW                    matlab.ui.control.TextArea
        NumberofnonzeroderivativesofwLabel  matlab.ui.control.Label
        nonzeroDerU                    matlab.ui.control.TextArea
        NumberofnonzeroderivativesofuLabel  matlab.ui.control.Label
        KnownparametersCheckBox        matlab.ui.control.CheckBox
        MainstructuralidentifiabilityandobservabilityoptionsLabel  matlab.ui.control.Label
        MaxLieTimeEditField            matlab.ui.control.NumericEditField
        Maxtimeforcalculating1LiederivativeEditFieldLabel  matlab.ui.control.Label
        InitialconditionsCheckBox      matlab.ui.control.CheckBox
        SymbNumCheckBox                matlab.ui.control.CheckBox
        DECOMPOSITIONTab               matlab.ui.container.Tab
        TheseoptionsaredisabledforthisalgorithmLabel  matlab.ui.control.Label
        OptionaldecompositionCheckBox  matlab.ui.control.CheckBox
        OnlydecomposemodelifthewholemodelistoolargeLabel  matlab.ui.control.Label
        ForcedecompositionCheckBox     matlab.ui.control.CheckBox
        TabGroup2                      matlab.ui.container.TabGroup
        OptimizationbaseddescompositionTab  matlab.ui.container.Tab
        MaxNumStatesSubmEditField      matlab.ui.control.NumericEditField
        MaximumnumberofstatesinthesubmodelsEditFieldLabel  matlab.ui.control.Label
        MaxTimeAlloweOptEditField      matlab.ui.control.NumericEditField
        MaximumtimeallowedforeveryoptimizationEditFieldLabel  matlab.ui.control.Label
        IfoptimizationbaseddecompositionisusedLabel  matlab.ui.control.Label
        DefinedbyuserTab               matlab.ui.container.Tab
        submodelosTextArea             matlab.ui.control.TextArea
        Example12pressenter34pressenter523Label  matlab.ui.control.Label
        SubmodelsLabel                 matlab.ui.control.Label
        submodelUserCheckBox           matlab.ui.control.CheckBox
        Usesubmodelsspecifiedbytheuser1orfoundbyoptimization0Label  matlab.ui.control.Label
        DoyouwanttodescomposethemodelLabel_2  matlab.ui.control.Label
        DECOMPOSITIONOPTIONSapplyonlytotheFISPOalgorithmLabel  matlab.ui.control.Label
        PathMeigoEditField             matlab.ui.control.EditField
        PathMeigoEditFieldLabel_2      matlab.ui.control.Label
        MULTIEXPTab                    matlab.ui.container.Tab
        TheseoptionsaredisabledforthisalgorithmLabel_2  matlab.ui.control.Label
        NumberofexperimentsEditField   matlab.ui.control.NumericEditField
        NumberofexperimentsEditFieldLabel  matlab.ui.control.Label
        TabGroup2_2                    matlab.ui.container.TabGroup
        KnownunknowninputTab           matlab.ui.container.Tab
        knowderivTextArea_2            matlab.ui.control.TextArea
        NumberofnonzeroknowninputderivativesineachexperimentsLabel_2  matlab.ui.control.Label
        knowderivTextArea              matlab.ui.control.TextArea
        NumberofnonzeroknowninputderivativesineachexperimentsLabel  matlab.ui.control.Label
        SetmanuallytheinitialconditionsforeachexperimentLabel_4  matlab.ui.control.Label
        uknowCheckBox                  matlab.ui.control.CheckBox
        knowMultiCheckBox              matlab.ui.control.CheckBox
        SetmanuallytheinitialconditionsforeachexperimentLabel_2  matlab.ui.control.Label
        InitialconditionsTab           matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        WhichTab                       matlab.ui.container.Tab
        wichicsMultiTextArea           matlab.ui.control.TextArea
        NumberofnonzeroknowninputderivativesineachexperimentsLabel_7  matlab.ui.control.Label
        ICsTab                         matlab.ui.container.Tab
        icsMultiTextArea               matlab.ui.control.TextArea
        InitialconditionsLabel_2       matlab.ui.control.Label
        NumberofnonzeroknowninputderivativesineachexperimentsLabel_6  matlab.ui.control.Label
        ICsMultCheckBox                matlab.ui.control.CheckBox
        SetmanuallytheinitialconditionsforeachexperimentLabel  matlab.ui.control.Label
        ExecutemultiexperimentanalysisLabel  matlab.ui.control.Label
        ExecuteSwitch                  matlab.ui.control.ToggleSwitch
        MULTIEXPERIMENTOPTIONSapplytothe3SIOalgorithmsLabel  matlab.ui.control.Label
        ORCDFTab                       matlab.ui.container.Tab
        TheseoptionsaredisabledforthisalgorithmLabel_3  matlab.ui.control.Label
        DelModelORCCheckBox            matlab.ui.control.CheckBox
        DisplaygraphicsCheckBox        matlab.ui.control.CheckBox
        NumWParEditField               matlab.ui.control.NumericEditField
        NumberofworkersforparallelpoolLabel  matlab.ui.control.Label
        ParalORCCheckBox               matlab.ui.control.CheckBox
        PARALLELOPTIONSLabel           matlab.ui.control.Label
        MaxNumItEditField              matlab.ui.control.NumericEditField
        MaxnumberofiterationsEditFieldLabel  matlab.ui.control.Label
        MaxTimeEditField               matlab.ui.control.NumericEditField
        MaxcomputationtimeforthelastiterationEditFieldLabel  matlab.ui.control.Label
        AFFINEOPTIONSapplyonlytotheORCDFalgorithmLabel  matlab.ui.control.Label
        LIESYMMETRIESTab               matlab.ui.container.Tab
        TheseoptionsaredisabledforthisalgorithmLabel_4  matlab.ui.control.Label
        PathEditField                  matlab.ui.control.EditField
        PathEditFieldLabel             matlab.ui.control.Label
        existResultsCheckBox           matlab.ui.control.CheckBox
        solverLieCheckBox              matlab.ui.control.CheckBox
        MaxDegLieserEditField          matlab.ui.control.NumericEditField
        MaximumdegreeofLieseriesEditFieldLabel  matlab.ui.control.Label
        DegAnsatzEditField             matlab.ui.control.NumericEditField
        DegreeofAnsatzpolynomialEditFieldLabel  matlab.ui.control.Label
        TypeAnsatzEditField            matlab.ui.control.NumericEditField
        TypeofAnsatzEditFieldLabel     matlab.ui.control.Label
        Multivariate3Label             matlab.ui.control.Label
        Partiallyvariate2Label         matlab.ui.control.Label
        Univariate1Label               matlab.ui.control.Label
        onlyforLie_SymmetryAutoReparLabel  matlab.ui.control.Label
        LIESYMMETRIESREPARAMETERIZATIONOPTIONSLabel  matlab.ui.control.Label
        STRIKEGOLDDPanel               matlab.ui.container.Panel
    end

    
    properties (Access = private)
        OptAlg % Type of analysis chosen
        RankNumeric % Cálculo numérico del rango
        ICs % Usar condiciones iniciales
        maxLieTime % Máximo tiempo de cálculo de 1 der. de Lie
        derU % Número de derivadas del vector de entradas 
        derW % Número de derivadas del vector de entradas desconocidas
        ForceDecomp % Forzar descomposición
        optDecomp % Descomponer si es muy grande
        maxTimAl %  Max. time allowed for every optimization
        maxStates % Max. number of states in the submodels
        userSub % When decomposing model, use submodels specified by the user (= 1) or found by optimization (= 0)
        MaxTimORC % Max.computation time for the last iteration
        MaxItORC % Max. number of iterations
        ParalORC % Use parallel toolbox (=1) or not (=0) to calculate partial ranks.
        NumWorORC % Number of workers for parallel pool.
        DGraphORC % Display graphics (=1) or not (=0)
        DelModORC % Delete affine model when finished (=1) or not (=0).
        PathMeigo % Ruta de Meigo
        multiExp % Execute multi-experiment analysis
        TyAnsLie % Type of Ansatz
        DegAnsLie % Degree of Ansatz Polynomial
        MaxDegLie % Maximum degree of Lie series
        solverLie % Use ode solver (=1) or not (=0)
        resultLie % If the model has already been analysed with STRIKE-GOLDD (=1) or not (=0)
        pathResLie % .mat file to use if use_existing_results = 1        
        submod % User-defined submodels for decomposition
        ICsMulti % Set manually the initial conditions for each experiment (=1) or not (=0)
        knowMulti % Set manually the number of non-zero known input derivatives in each experiment (=1) or not (=0).
        uknowMulti % Set manually the number of non-zero unknown input derivatives in each experiment (=1) or not (=0).
        numExpMulti % Number of experiments
        knDerMulti % Number of non-zero known input derivatives in each experiment
        uknDerMulti % Number of non-zero unknown input derivatives in each experiment
        icsMulti % Multi-experiment initial conditions  
        WicsMulti % Which initial conditions are replaced
        pars % Parameters assumed known, or already classified as identifiable
        selectedButton % Caja de los botones (Create new model, Use existing model)
        namefiles % Lista con los modelos de la carpeta
        ModelnameEditField % Opción que se escoge en la lista de modelos
        oldpath % Ruta de la carpeta principal de STRIKE-GOLDD
    end
    
    methods (Access = private)
        
        %%% DIALOG BOX TO CLOSE THE APP
        function mycallback(app,src,event)
            if event.SelectedOption == "OK"
                %delete('options_aux.m')
                delete(app.SG);
            end
        end
    end
    
    methods (Access = public)
        %Esta función crea el fichero auxiliar de opciones que va a
        %utilizar SG para ejecutar el análisis.
        function overwrite_options(app,modelname,paths)
            fid = fopen('options_aux.m', 'w');
            
            fprintf(fid,'function [modelname,paths,opts,submodels,prev_ident_pars]=options()\n');
            
            fprintf(fid,'modelname = "%s";\n',modelname);
            fprintf(fid,'modelname = convertStringsToChars(modelname);\n');
               
            fprintf(fid,'opts.algorithm = %d;\n',app.OptAlg);
            
            fprintf(fid,'opts.maxLietime = %d;\n',app.maxLieTime);
            
            fprintf(fid,'opts.nnzDerU    = [');
            fprintf(fid,repmat('%d ', 1, length(app.derU)), app.derU);
            fprintf(fid,'];\n');
            
            fprintf(fid,'opts.nnzDerW    = [');
            fprintf(fid,repmat('%d ', 1, length(app.derW)), app.derW);
            fprintf(fid,'];\n');
            

            fprintf(fid,'opts.numeric    = %i;\n',app.RankNumeric);
            fprintf(fid,'opts.replaceICs = %i;\n', app.ICs);
            fprintf(fid,'prev_ident_pars = prevIdPars();\n');
            
            fprintf(fid, 'opts.affine_tStage = %d;\n', app.MaxTimORC);
            fprintf(fid, 'opts.affine_kmax = %d;\n', app.MaxItORC); 
            fprintf(fid, 'opts.affine_parallel = %i;\n', app.ParalORC);
            fprintf(fid, 'opts.affine_workers = %i;\n', app.NumWorORC);
            fprintf(fid, 'opts.affine_graphics = %i;\n', app.DGraphORC);
            fprintf(fid, 'opts.affine_delete_model = %i;\n', app.DelModORC);            
            
            fprintf(fid, 'opts.forcedecomp = %i;\n', app.ForceDecomp);
            fprintf(fid, 'opts.decomp = %i;\n',app.optDecomp);
            fprintf(fid, 'opts.maxOpttime = %d;\n', app.maxTimAl);
            fprintf(fid, 'opts.maxstates = %i;\n', app.maxStates);
            fprintf(fid, 'opts.decomp_user = %i;\n', app.userSub);
            
            for i = 1:length(app.submod)
                fprintf(fid, 'submodels{%i} = [',i);
                fprintf(fid, repmat('%d ', 1, length(app.submod(i))), app.submod{i});
                fprintf(fid,'];\n');
            end

            fprintf(fid, 'opts.multiexp = %i;\n', app.multiExp);    
            fprintf(fid, 'opts.multiexp_numexp = %i;\n', app.numExpMulti);     
            fprintf(fid, 'opts.multiexp_user_nnzDerU = %i;\n', app.knowMulti);
            fprintf(fid, 'opts.multiexp_nnzDerU = [');
            fprintf(fid,repmat('%d ', 1, length(app.knDerMulti)), app.knDerMulti);
            fprintf(fid, '];\n');
            fprintf(fid, 'opts.multiexp_user_nnzDerW = %i;\n', app.uknowMulti);
            fprintf(fid, 'opts.multiexp_nnzDerW = [');
            fprintf(fid,repmat('%d ', 1, length(app.uknDerMulti)), app.uknDerMulti);
            fprintf(fid, '];\n'); 
            fprintf(fid, 'opts.multiexp_user_ics = %i;\n', app.ICsMulti);
            
            [row,columns] = size(app.icsMulti);
            fprintf(fid, 'opts.multiexp_ics = [');
            for i = 1:columns-1
                fprintf(fid,'transpose(['); 
                fprintf(fid, repmat('%d ', 1, length(app.icsMulti(:,i))), app.icsMulti(:,i));
                fprintf(fid,']),');
            end            
            fprintf(fid,'transpose([');
            fprintf(fid, repmat('%d ', 1, length(app.icsMulti(:,end))), app.icsMulti(:,end));
            fprintf(fid,'])];\n');
            
            [row,columns] = size(app.WicsMulti);
            fprintf(fid, 'opts.multiexp_known_ics = [');
            for i = 1:columns-1
                fprintf(fid,'transpose(['); 
                fprintf(fid, repmat('%d ', 1, length(app.WicsMulti(:,i))), app.WicsMulti(:,i));
                fprintf(fid,']),');
            end            
            fprintf(fid,'transpose([');
            fprintf(fid, repmat('%d ', 1, length(app.WicsMulti(:,end))), app.WicsMulti(:,end));
            fprintf(fid,'])];\n');
            
            fprintf(fid, 'opts.ansatz = %i; \n', app.TyAnsLie);
            fprintf(fid, 'opts.degree = %i; \n', app.DegAnsLie); 
            fprintf(fid, 'opts.tmax = %i;\n', app.MaxDegLie); 
            fprintf(fid, 'opts.ode_n  = %i;\n', app.solverLie); 
            fprintf(fid, 'opts.use_existing_results = %i;\n', app.resultLie); 
            fprintf(fid, 'opts.results_file = "%s";\n', app.pathResLie); 
            fprintf(fid, 'opts.results_file = convertStringsToChars(opts.results_file);\n');
            
            fprintf(fid,'paths.meigo = "%s";\n',app.PathMeigo);
            fprintf(fid,'paths.meigo = convertStringsToChars(paths.meigo);\n');
            fprintf(fid,'paths.models = "%s";\n',paths.models);
            fprintf(fid,'paths.models = convertStringsToChars(paths.models);\n');
            fprintf(fid,'paths.results = "%s";\n',paths.results);
            fprintf(fid,'paths.results = convertStringsToChars(paths.results);\n');
            fprintf(fid,'paths.functions = "%s";\n',paths.functions);
            fprintf(fid,'paths.functions = convertStringsToChars(paths.functions);\n');
                        
            fclose(fid);
            
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %COMPROBAMOS QUE LA CARPETA DEL WORKSPACE ES LA DE STRIKEGOLDD
            app.oldpath = pwd;            
            
            %rutafinal = app.oldpath(end-32:end);
            %comparar = strcmp(rutafinal,'\strike-goldd-master\STRIKE-GOLDD');
            
            rutafinal = app.oldpath(end-12:end);
            comparar = strcmp(rutafinal,'\STRIKE-GOLDD');
            
            optsMes = struct('WindowStyle','modal',... 
              'Interpreter','tex');
            if comparar == 0
                uiwait(warndlg([ '\fontsize{10} You should run the app from \it STRIKE-GOLDD \rm folder. Press OK ' ...
                    'to close the APP and to change the Current folder'],'Error in path',optsMes));
                
                delete(app.SG);
                return
            end
            
            % AÑADIMOS LA CARPETA FUNCIONES DE LA APP AL ÁMBITO DE
            % TRABAJO DE LA APP 
            newpath = strcat(app.oldpath,filesep,'functions\aux_APP');        
            addpath(genpath(newpath));
            %%%
            
            % Reiniciamos la funciones de PrevIdPars para que aparezca vacío         
            SGfolder = cd;
            cd 'functions\aux_APP'
            fid = fopen('prevIdPars.m', 'w');
            fprintf(fid,'function prev_pars = prevIdPars()\n');
            fprintf(fid,'prev_pars = [];\n');
            fprintf(fid,'end');
            fclose(fid);
            cd(SGfolder)
            %%%
            
            %%% LISTA DESPLEGABLE %%%%%%%%%%%%%%%%%%%%%%%%
            %(Leemos los archivos .mat e incluimos los nombres dentro 
            % de una variable para la lista desplegable)
            SGfolder = cd;
            cd models
            
            Files=dir('*.mat*');
            for k=1:length(Files)
               FileNames{k}=Files(k).name;
            end
                        
            for i = 1:length(FileNames) 
                app.namefiles{i} = erase(FileNames{i},'.mat');
            end
            
            cd(SGfolder)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            SGfolder = cd;
            app.Lamp.Color = 'red';
            drawnow %Para que se aplique el color nuevo 'red'
            
            install
            
            %Opciones del dialog message warning
            optsMes = struct('WindowStyle','modal',... 
              'Interpreter','tex');
            
            app.ModelnameEditField = app.ModelnameDropDown.Value;
            
            %Cargamos el modelo y limpiamos todas las variables salvo u y w
            %que las usaremos después para las comprobaciones
            load(app.ModelnameEditField);
            VectorX = x;
            VectorU = u; 
            ExisteW = exist('w');
            if ExisteW == 1
                VectorW = w;
                clear w
            end
            clear f h ics known_ics p x u
            
            %%----------------%% GET VALUES BY DEFAULT %%----------------%%
            value1 = app.AlgorithmDropDown.Value;
            if strcmp(value1,'FISPO') == 1
                app.OptAlg = 1;
            elseif strcmp(value1,'Prob_Obs_Test') == 1
                app.OptAlg = 2;
            elseif strcmp(value1,'ORC DF') == 1
                app.OptAlg = 3; 
            elseif strcmp(value1,'Lie_Symmetry') == 1
                app.OptAlg = 4;
            elseif strcmp(value1,'AutoRepar') == 1
                app.OptAlg = 5;            
            end
            
            
            app.RankNumeric = app.SymbNumCheckBox.Value;
            app.ICs = app.InitialconditionsCheckBox.Value;
            app.maxLieTime = app.MaxLieTimeEditField.Value;
            app.derU = app.nonzeroDerU.Value;
            app.derW = app.nonzeroDerW.Value;  
            app.PathMeigo = app.PathMeigoEditField.Value;
            app.ForceDecomp = app.ForcedecompositionCheckBox.Value;
            app.optDecomp = app.OptionaldecompositionCheckBox.Value;
            app.maxTimAl = app.MaxTimeAlloweOptEditField.Value;
            app.maxStates = app.MaxNumStatesSubmEditField.Value;
            app.userSub = app.submodelUserCheckBox.Value;
            app.MaxTimORC = app.MaxTimeEditField.Value;
            app.MaxItORC = app.MaxNumItEditField.Value;
            app.ParalORC = app.ParalORCCheckBox.Value;
            app.NumWorORC = app.NumWParEditField.Value;
            app.DGraphORC = app.DisplaygraphicsCheckBox.Value;
            app.DelModORC = app.DelModelORCCheckBox.Value;
            app.multiExp = app.ExecuteSwitch.Value;
            app.TyAnsLie = app.TypeAnsatzEditField.Value;
            app.DegAnsLie = app.DegAnsatzEditField.Value;
            app.MaxDegLie = app.MaxDegLieserEditField.Value;
            app.solverLie = app.solverLieCheckBox.Value;
            app.resultLie = app.existResultsCheckBox.Value;
            app.pathResLie = app.PathEditField.Value;
            app.submod = app.submodelosTextArea.Value;
            app.ICsMulti = app.ICsMultCheckBox.Value;
            app.knowMulti = app.knowMultiCheckBox.Value;
            app.uknowMulti = app.uknowCheckBox.Value;
            app.numExpMulti = app.NumberofexperimentsEditField.Value;
            app.knDerMulti = app.knowderivTextArea.Value;
            app.uknDerMulti = app.knowderivTextArea_2.Value;
            app.icsMulti = app.icsMultiTextArea.Value;
            app.WicsMulti = app.wichicsMultiTextArea.Value;
            %%-----------------------------------------------------%%

            app.pars = prevIdPars();

            
            %%% Multiexperiment: 
            app.multiExp = str2double(app.multiExp);
            
            
            %%----------------%% PASAR LOS CELL ARRAYS A ARRAYS NUMÉRICOS %%----------------%%
            
            %%% ¡SUBMODELS!
            %value: cell array of chars
            b = app.submod;
            
            %find empty elements
            j=1;
            index = [];
            for i = 1:length(b)
                if b(i) == ""
                    index(j) = i;
                    j = j+1;
                end
            end
            % delete empty elements
            for i = 1:length(index)
                b(index(i)) = [];
            end
            
            % Delete [], spaces, ','
            bb = erase(b,{'[',']',' ', ','});
                        
            for i = 1:length(bb)
                v = zeros(length(bb(i)));
                % to pass the numbers in every char to an array 1-D
                for k = 1:length(bb{i})
                    number = str2double(bb{i}(k));
                    v(k) = number;
                end
                
                % cell array to store the 1-D arrays
                vv{i} = v;
                
                % restart 1-D array
                clear v
            end         
            app.submod = vv;  
            %%%-------------------------------------------------------
            
            clear b bb j index v vv 
            
            %%%-------------------------------------------------------

            %%% Número de derivadas de las entradas conocidas en cada
            %%% experimento. Es una matriz: las filas son cada función de
            %%% entrada, las columnas el número de experimento. El valor es el número de
            %%% derivadas. 
            b = app.knDerMulti;
            j=1;
            index = [];
            for i = 1:length(b)
                if b(i) == ""
                    index(j) = i;
                    j = j+1;
                end
            end
            for i = 1:length(index)
                b(index(i)) = [];
            end
            bb = erase(b,{'[',']',' ', ','});        
            for i = 1:length(bb)
                v = zeros(length(bb(i)));
                for k = 1:length(bb{i})
                    number = str2double(bb{i}(k));
                    v(k) = number;
                end
                vv{i} = v;
                clear v
            end       
            % cell array -> array
            arrayV = zeros(length(vv),length(vv{1}));
            for i = 1:length(vv)
                arrayV(i,:) = vv{i};
            end
            
            app.knDerMulti = arrayV;  
            
            %COMPROBACIÓN tamaño del array de nº de derivadas de u en cada
            %experimento:
            %   - NumU tiene que ser igual que las filas de app.knDerMulti
            %   - Las columnas de app.knDerMulti tiene que ser iguales al nº de expm.
            NumU = length(VectorU);
            if app.multiExp == 1 %Tiene que estar activado el análisis MultiExp
                if isempty(VectorU) == 0 %El vector de entradas no puede estar vacío
                    sz = size(app.knDerMulti);
                    if NumU ~= sz(1)
                        uiwait(warndlg([' \fontsize{9}  Warning: the option "Number of non-zero known input ' ...
                            'derivatives in each experiments" should be an array woth as many rows as ' ...
                            'the number of inputs. Press' ...
                            ' OK to close this message and modify it accordingly.'], ...
                            'Error in options',optsMes));
                        drawnow
                        app.Lamp.Color = 'g';                          
                        %delete(app.SG);
                        return
                        %close(app.SG)
                    end
                    if app.numExpMulti ~= sz(2)
                        uiwait(warndlg(['\fontsize{9} Warning: the option "Number of non-zero known ' ...
                            'input derivatives in each experiments"' ...
                            ' should be an array with as many columns as the number of Experiments. Press' ...
                            ' OK to close this message and modify it accordingly.'], ...
                            'Error in options',optsMes));
                        drawnow
                        app.Lamp.Color = 'g';                          
                        %delete(app.SG);
                        return
                    end
                end
            end
            
            %%%-------------------------------------------------------
            
            clear b bb j index v vv arrayV
            
            %%%-------------------------------------------------------
            
            %%% Número de derivadas de las entradas desconocidas en cada
            %%% experimento. Es una matriz: las filas son cada función de
            %%% entrada desc., las columnas el número de experimento. El valor es el número de
            %%% derivadas. 
            b = app.uknDerMulti;
            j=1;
            index = [];
            for i = 1:length(b)
                if b(i) == ""
                    index(j) = i;
                    j = j+1;
                end
            end
            for i = 1:length(index)
                b(index(i)) = [];
            end
            bb = erase(b,{'[',']',' ', ','});       
            for i = 1:length(bb)
                v = zeros(length(bb(i)));
                for k = 1:length(bb{i})
                    number = str2double(bb{i}(k));
                    v(k) = number;
                end
                vv{i} = v;
                clear v
            end       
            arrayV = zeros(length(vv),length(vv{1}));
            for i = 1:length(vv)
                arrayV(i,:) = vv{i};
            end
            app.uknDerMulti = arrayV;  
            
            %COMPROBACIÓN tamaño del array de nº de derivadas de w en cada
            %experimento:
            %   - NumW tiene que ser igual que las filas de app.uknDerMulti
            %   - Las columnas de app.uknDerMulti tiene que ser iguales al nº de expm.
                        
            if app.multiExp == 1
                if ExisteW == 1 %hay que comprobar que existe la variable W, no siempre se pasa en el modelo
                    NumW = length(VectorW);
                    if isempty(VectorW) == 0
                        sz = size(app.uknDerMulti);
                        if NumW ~= sz(1)
                            uiwait(warndlg(['\fontsize{9} Warning: the option "Number of non-zero ' ...
                                'unknown input derivatives in ' ...
                                'each experiments" should be an array with as many rows as the number of unknown ' ...
                                'inputs. Press OK to close this message and modify it accordingly.'], ...
                            'Error in options',optsMes));
                            
                            drawnow
                            app.Lamp.Color = 'g';                              
                            %delete(app.SG);
                            return
                            %close(app.SG)
                        end
                        if app.numExpMulti ~= sz(2)
                            uiwait(warndlg(['\fontsize{9}  Warning: the option "Number of non-zero unknown input derivatives in ' ...
                                'each experiments" should be an array with as many columns as the number of Experiments. Press' ...
                                ' OK to close this message and modify it accordingly.'], ...
                            'Error in options',optsMes));
                            drawnow
                            app.Lamp.Color = 'g';                              
                            %delete(app.SG);
                            return
                        end
                    end
                end
            end
            
            %%%-------------------------------------------------------

            
            clear b bb j index v vv arrayV
            
            
            %%%-------------------------------------------------------
            
            %%% Matriz donde los vectores columnas son las condiciones
            %%% iniciales de todos los estados para un experimento dado. 
            b = app.icsMulti;
            j=1;
            index = [];
            for i = 1:length(b)
                if b(i) == ""
                    index(j) = i;
                    j = j+1;
                end
            end
            for i = 1:length(index)
                b(index(i)) = [];
            end
            bb = erase(b,{'[',']',' ', ','});         
            for i = 1:length(bb)
                v = zeros(length(bb(i)));
                for k = 1:length(bb{i})
                    number = str2double(bb{i}(k));
                    v(k) = number;
                end
                vv{i} = v;
                clear v
            end    
            arrayV = zeros(length(vv),length(vv{1}));
            for i = 1:length(vv)
                arrayV(i,:) = vv{i};
            end
            %Transpose
            app.icsMulti = arrayV.';  
            
            %COMPROBACIÓN tamaño del array de nº de estados para cada experimento:
            %   - NumX tiene que ser igual que las filas de app.icsMulti
            %   - Las columnas de app.icsrMulti tiene que ser iguales al nº de expm.
                        
            if app.multiExp == 1
                if app.ICsMulti == 1
                    NumX = length(VectorX);
                    sz = size(app.icsMulti);
                    if NumX ~= sz(1)
                        uiwait(warndlg(['\fontsize{9} Warning: the option "Multiexperiment Initial Conditions" should ' ...
                            'be an array with as many rows as there are states. Press OK to  close this' ...
                            ' message and modify it accordingly.'], ...
                            'Error in options',optsMes));
                        drawnow
                        app.Lamp.Color = 'g';         
                        %delete(app.SG);
                        return
                        %close(app.SG)
                    end
                    if app.numExpMulti ~= sz(2)
                        uiwait(warndlg(['\fontsize{9} Warning: the option "Multiexperiment Initial Conditions" should' ...
                            'be an array with as many' ...
                            'columns as the number of Experiments. Press' ...
                            ' OK to close the message and modify it accordingly.'], ...
                            'Error in options',optsMes));
                        
                        drawnow
                        app.Lamp.Color = 'g';                          
                        %delete(app.SG);
                        return
                    end
                end
            end
    
            %%%-------------------------------------------------------
            
            clear b bb j index v vv arrayV           
            
            %%%-------------------------------------------------------
           
            %%% Matriz que indica qué elementos(estados) de la matriz de condiciones
            %%% iniciales se sustituyen en el análisis para cada experimento 
            b = app.WicsMulti;
            j=1;
            index = [];
            for i = 1:length(b)
                if b(i) == ""
                    index(j) = i;
                    j = j+1;
                end
            end
            for i = 1:length(index)
                b(index(i)) = [];
            end
            bb = erase(b,{'[',']',' ', ','});       
            for i = 1:length(bb)
                v = zeros(length(bb(i)));
                for k = 1:length(bb{i})
                    number = str2double(bb{i}(k));
                    v(k) = number;
                end
                vv{i} = v;
                clear v
            end       
            arrayV = zeros(length(vv),length(vv{1}));
            for i = 1:length(vv)
                arrayV(i,:) = vv{i};
            end
            app.WicsMulti = arrayV.'; 
            
            %COMPROBACIÓN tamaño del array de nº de estados para cada experimento:
            %   - NumX tiene que ser igual que las filas de app.WicsMulti
            %   - Las columnas de app.WicsMulti tiene que ser iguales al nº de expm.
                        
            if app.multiExp == 1
                if app.ICsMulti == 1
                    sz = size(app.WicsMulti);
                    if NumX ~= sz(1)
                        uiwait(warndlg(['\fontsize{9} Warning: the option  "Which Multiexperiment Initial Conditions" should be an array' ...
                            'with as many rows as there are states. Press OK to' ...
                            '  close this message and modify it accordingly.'], ...
                            'Error in options',optsMes));
                        
                        drawnow
                        app.Lamp.Color = 'g';                          
                        %delete(app.SG);
                        return
                        %close(app.SG)
                    end
                    if app.numExpMulti ~= sz(2)
                        uiwait(warndlg(['\fontsize{9} Warning: the option "Which Multiexperiment Initial Conditions" should' ...
                            'be an array with as many' ...
                            'columns as the number of Experiments. Press' ...
                            ' OK to close this message and modify it accordingly.'], ...
                            'Error in options',optsMes));
                        
                        %delete(app.SG);
                        drawnow
                        app.Lamp.Color = 'g';                          
                        return
                    end
                end
            end
    
            %%%-------------------------------------------------------
            
            clear b bb j index v vv arrayV           
            
            %%%-------------------------------------------------------
            
            %%% Vector donde se indica el número de derivadas para cada
            %%% función de entrada.
            %En este tramo de código se tiene que pasar el texto, p.e. '[inf 1 2 inf]'
            %a una matriz con esos posibles elemento. En lugar de espacios
            %también pueden ser comas.
            
            b = app.derU{1};
            
            j=1;
            index = [];
            
            bb = erase(b,{'[',']'});    
            b = bb;
            
            %Eliminar o espacio o coma cuando es [2, 3]
            i = 1;
            while i < length(b)
                if b(i) == ' ' & b(i+1) == ','
                    b(i) = [];
                    i = i-1;
                elseif b(i) == ',' &  b(i+1) == ' '
                    b(i+1) = [];
                    i = i-1;
                end
                i = i+1;
            end
            clear i

            %Identifico los espacios para saber donde se separan los elementos (index)
            for i = 1:length(b)
                if b(i) == ' '
                    index(j) = i;
                    j = j+1;
                elseif b(i) ==  ','
                    index(j) = i;
                    j = j+1;        
                end
            end
            
            if isempty(index)
                app.derU = str2double(b);
            else    
                matrix1(1) = str2double( b( 1: index(1)-1) );
               
                for i = 1:length(index)-1
                    
                    %1º posición de b: index(1)+1
                    %2º posición de b: index(2)-1
                    pos1 = index(i)+1;
                    pos2 = index(i+1)-1;
                
                    matrix1(i+1) = str2double(b(pos1:pos2));
                
                    %b(index(i)) = [];
                end
                
                matrix1(length(index)+1) = str2double(b(index(end):end));
                app.derU = matrix1;
            end
            
            %COMPROBACIÓN:
            %   - app.derU tiene que tener la misma dimensión que el
            %   vectorU
            if isnan(app.derU)
                app.derU = [];
            end
            if NumU > length(app.derU)
                uiwait(warndlg(['\fontsize{9} Warning: the option "Number of nonzero derivatives of u" ' ...
                    'should be a vector with as many elements as the number of measured inputs ' ...
                    'of the model. Press OK to close this message and modify it accordingly'], ...
                            'Error in options',optsMes));
            
                drawnow
                app.Lamp.Color = 'g';                
                %delete(app.SG);
                return                
            end
            aaa = app.derU;
            
            %%%-------------------------------------------------------
            
            clear b j index matrix1 pos1 pos2 bb
            
            %%%-------------------------------------------------------
            
            %%% ¡numbers nonzero derivatives w!
            b = app.derW{1};
            
            j=1;
            index = [];
            
            bb = erase(b,{'[',']'});    
            b = bb;
            
            %Eliminar o espacio o coma cuando es [2, 3]
            i = 1;
            while i < length(b)
                if b(i) == ' ' & b(i+1) == ','
                    b(i) = [];
                    i = i-1;
                elseif b(i) == ',' &  b(i+1) == ' '
                    b(i+1) = [];
                    i = i-1;
                end
                i = i+1;
            end
            clear i

            %Identifico los espacios para saber donde se separan los elementos (index)
            for i = 1:length(b)
                if b(i) == ' '
                    index(j) = i;
                    j = j+1;
                elseif b(i) ==  ','
                    index(j) = i;
                    j = j+1;        
                end
            end
            
            if isempty(index)
                app.derW = str2double(b);
                
            else    
                matrix1(1) = str2double( b( 1: index(1)-1) );
               
                for i = 1:length(index)-1
                    
                    %1º posición de b: index(1)+1
                    %2º posición de b: index(2)-1
                    pos1 = index(i)+1;
                    pos2 = index(i+1)-1;
                
                    matrix1(i+1) = str2double(b(pos1:pos2));
                
                    %b(index(i)) = [];
                end
                
                matrix1(length(index)+1) = str2double(b(index(end):end));
                app.derW = matrix1;
            end

            %COMPROBACIÓN:
            %   - app.derW tiene que tener la misma dimensión que el
            %   vectorW
            if isnan(app.derW)
                app.derW = [];
            end
            if ExisteW == 1
                if isempty(VectorW) == 0 
                    NumW = length(VectorW);
                    if NumW > length(app.derW)
                        uiwait(warndlg(['\fontsize{9} Warning: the option "Number of nonzero derivatives of w" ' ...
                    'should be a vector with as many elements as the number of unmeasured inputs ' ...
                    'of the model. Press OK to close this message and modify it accordingly'], ...
                            'Error in options',optsMes));
                        
                        drawnow
                        app.Lamp.Color = 'g';                          
                        %delete(app.SG);
                        return                
                    end
                
                end
            end
            clear b j index matrix1 pos1 pos2 bb

            %%----------------%%         PASAR LOS DATOS DE LA APP    %%----------------%%
            %%----------------%%     A LAS VARIABLES CORRESPONDIENTES %%----------------%%
            %%----------------%%             DE LAS OPTIONS           %%----------------%%
            
            modelname = app.ModelnameEditField;
            
            %%% PATHS
            paths.meigo = app.PathMeigo;
            paths.models    = strcat(pwd,filesep,'models');
            paths.results   = strcat(pwd,filesep,'results');
            paths.functions = strcat(pwd,filesep,'functions');
            
            %%% IDENTIFIABILITY OPTIONS
            opts.algorithm = app.OptAlg;
            opts.numeric = app.RankNumeric;
            opts.replaceICs = app.ICs;
            opts.maxLietime = app.maxLieTime;
            opts.nnzDerU    = app.derU;     
            opts.nnzDerW    = app.derW;
            %prev_ident_pars = app.pars; Esta variable se obtiene
            %ejecutando la función prevIdPars desde el fichero options
            %auxiliar
            
            
            %%% DECOMPOSITION OPTIONS
            opts.forcedecomp = app.ForceDecomp;
            opts.decomp = app.optDecomp;
            opts.maxOpttime = app.maxTimAl;
            opts.maxstates = app.maxStates;
            opts.decomp_user = app.userSub;
            %submodels       = app.submod; Esta variable se escribe en las
            %options desde la propia función que crea el fichero options
            %auxiliar
            
            
            %%% OPTIONS FOR AFFINE SYSTEMS
            opts.unidentif  = 0;          
            opts.affine_tStage = app.MaxTimORC;
            opts.affine_kmax = app.MaxItORC;
            opts.affine_parallel     = app.ParalORC;     
            opts.affine_workers      = app.NumWorORC;     
            opts.affine_graphics     = app.DGraphORC;    
            opts.affine_delete_model = app.DelModORC;

            %%% MULTI-EXPERIMENT OPTIONS
            opts.multiexp              = app.multiExp;    
            opts.multiexp_numexp       = app.numExpMulti;     
            opts.multiexp_user_nnzDerU = app.knowMulti;    
            opts.multiexp_nnzDerU      = app.knDerMulti; 
            opts.multiexp_user_nnzDerW = app.uknowMulti;    
            opts.multiexp_nnzDerW      = app.uknDerMulti; 
            opts.multiexp_user_ics     = app.ICsMulti;    
            opts.multiexp_ics       = app.icsMulti;
            opts.multiexp_known_ics = app.WicsMulti;
                     
            %%% LIE SYMMETRIES & REPARAMETERIZATION OPTIONS
            opts.ansatz     = app.TyAnsLie; 
            opts.degree     = app.DegAnsLie; 
            opts.tmax       = app.MaxDegLie; 
            opts.ode_n      = app.solverLie; 
            opts.use_existing_results = app.resultLie; 
            opts.results_file = app.pathResLie;             
            
            %======= DEPRECATED OPTIONS (FOR COMPATIBILITY WITH OLDER VERSIONS) =======
            opts.unidentif  = 0;            % use method to try to establish unidentifiability instead of identifiability when using decomposition. 
            opts.nnzDerIn   = opts.nnzDerU; % deprecated option
            opts.checkObser = 1;            % check state observability, i.e. identifiability of initial conditions (1 = yes; 0 = no).
            opts.checkObsIn = 1;            % check input observability (1 = yes; 0 = no).
            opts.affine     = 0;            % use the ORC-DF algorithm for affine control systems (=1) or not(=0).
            
            %Pasamos todas las variables para escribir el fichero auxiliar
            %de options, a excepción de prevIdPars y submod que se escriben
            %desde ahí.
            overwrite_options(app,modelname,paths);
            
            
            uialert(app.SG,['The analysis ends when the Run button turns green (press OK to close this message)'],'Strike-Goldd');  
            
            STRIKE_GOLDD("options_aux.m");
            
            drawnow
            app.Lamp.Color = 'g';
            
            cd(SGfolder);
            delete('options_aux.m')
            
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            value = app.AlgorithmDropDown.Value;
            if strcmp(value,'FISPO') == 1
                app.OptAlg = 1;
                
                app.MaxLieTimeEditField.Visible = 'on';
                app.SymbNumCheckBox.Visible = 'on';
                app.InitialconditionsCheckBox.Visible = 'on';
                app.Maxtimeforcalculating1LiederivativeEditFieldLabel.Visible = 'on';
                
                %SHOW SETTINGS (Multiexp)
                app.NumberofexperimentsEditField.Visible = 'on';
                app.NumberofexperimentsEditFieldLabel.Visible = 'on';
                app.knowderivTextArea.Visible = 'on';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel.Visible = 'on';
                app.knowderivTextArea.Visible = 'on';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel.Visible = 'on';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel_2.Visible = 'on';
                app.uknowCheckBox.Visible = 'on';
                app.knowMultiCheckBox.Visible = 'on';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel_4.Visible = 'on';
                app.wichicsMultiTextArea.Visible = 'on';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_7.Visible = 'on';
                app.icsMultiTextArea.Visible = 'on';
                app.InitialconditionsLabel_2.Visible = 'on';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_6.Visible = 'on';
                app.ICsMultCheckBox.Visible = 'on';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel.Visible = 'on';
                app.ExecuteSwitch.Visible = 'on';
                app.ExecutemultiexperimentanalysisLabel.Visible = 'on';
                app.MULTIEXPERIMENTOPTIONSapplytothe3SIOalgorithmsLabel.Visible = 'on';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_2.Visible = 'on';
                
                
                %SHOW SETTINGS (Decomposition)
                app.OptionaldecompositionCheckBox.Visible = 'on';
                app.OnlydecomposemodelifthewholemodelistoolargeLabel.Visible = 'on';
                app.ForcedecompositionCheckBox.Visible = 'on';
                app.MaxNumStatesSubmEditField.Visible = 'on';
                app.MaximumnumberofstatesinthesubmodelsEditFieldLabel.Visible = 'on';
                app.MaxTimeAlloweOptEditField.Visible = 'on';
                app.MaximumtimeallowedforeveryoptimizationEditFieldLabel.Visible = 'on';
                app.IfoptimizationbaseddecompositionisusedLabel.Visible = 'on';
                app.submodelosTextArea.Visible = 'on';
                app.Example12pressenter34pressenter523Label.Visible = 'on';
                app.SubmodelsLabel.Visible = 'on';
                app.submodelUserCheckBox.Visible = 'on';
                app.Usesubmodelsspecifiedbytheuser1orfoundbyoptimization0Label.Visible = 'on';
                app.DoyouwanttodescomposethemodelLabel_2.Visible = 'on';
                app.DECOMPOSITIONOPTIONSapplyonlytotheFISPOalgorithmLabel.Visible = 'on';
                app.PathMeigoEditField.Visible = 'on';
                app.PathMeigoEditFieldLabel_2.Visible = 'on';    
                
                app.TheseoptionsaredisabledforthisalgorithmLabel_4.Visible = 'on'; %symmetries
                app.TheseoptionsaredisabledforthisalgorithmLabel_3.Visible = 'off';%ORC
                app.TheseoptionsaredisabledforthisalgorithmLabel_2.Visible = 'off';%multiexp
                app.TheseoptionsaredisabledforthisalgorithmLabel.Visible = 'off';%decomposition
                
                drawnow
                
                %SHOW SETTINGS (ORC)
                app.DelModelORCCheckBox.Visible = 'off';
                app.DisplaygraphicsCheckBox.Visible = 'off';
                app.NumWParEditField.Visible = 'off';
                app.NumberofworkersforparallelpoolLabel.Visible = 'off';
                app.ParalORCCheckBox.Visible = 'off';
                app.PARALLELOPTIONSLabel.Visible = 'off';
                app.MaxNumItEditField.Visible = 'off';
                app.MaxnumberofiterationsEditFieldLabel.Visible = 'off';
                app.MaxTimeEditField.Visible = 'off';
                app.MaxcomputationtimeforthelastiterationEditFieldLabel.Visible = 'off';
                app.AFFINEOPTIONSapplyonlytotheORCDFalgorithmLabel.Visible = 'off';
                
                %HIDE SETTINGS (LIESIMMETRIES)
                app.PathEditField.Visible = 'off';
                app.PathEditFieldLabel.Visible = 'off';
                app.existResultsCheckBox.Visible = 'off';
                app.solverLieCheckBox.Visible = 'off';
                app.MaxDegLieserEditField.Visible = 'off';
                app.MaximumdegreeofLieseriesEditFieldLabel.Visible = 'off';
                app.DegAnsatzEditField.Visible = 'off';
                app.DegreeofAnsatzpolynomialEditFieldLabel.Visible = 'off';
                app.TypeAnsatzEditField.Visible = 'off';
                app.TypeofAnsatzEditFieldLabel.Visible = 'off';
                app.Multivariate3Label.Visible = 'off';
                app.Partiallyvariate2Label.Visible = 'off';
                app.Univariate1Label.Visible = 'off';
                app.onlyforLie_SymmetryAutoReparLabel.Visible = 'off';
                app.LIESYMMETRIESREPARAMETERIZATIONOPTIONSLabel.Visible = 'off';
                %app.TheseoptionsaredisabledforthisalgorithmLabel_4.Visible = 'on';
                
            elseif strcmp(value,'Prob_Obs_Test') == 1
                app.OptAlg = 2;
                
                %SHOW SETTINGS (Multiexp)
                app.NumberofexperimentsEditField.Visible = 'on';
                app.NumberofexperimentsEditFieldLabel.Visible = 'on';
                app.knowderivTextArea.Visible = 'on';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel.Visible = 'on';
                app.knowderivTextArea.Visible = 'on';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel.Visible = 'on';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel_2.Visible = 'on';
                app.uknowCheckBox.Visible = 'on';
                app.knowMultiCheckBox.Visible = 'on';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel_4.Visible = 'on';
                app.wichicsMultiTextArea.Visible = 'on';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_7.Visible = 'on';
                app.icsMultiTextArea.Visible = 'on';
                app.InitialconditionsLabel_2.Visible = 'on';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_6.Visible = 'on';
                app.ICsMultCheckBox.Visible = 'on';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel.Visible = 'on';
                app.ExecuteSwitch.Visible = 'on';
                app.ExecutemultiexperimentanalysisLabel.Visible = 'on';
                app.MULTIEXPERIMENTOPTIONSapplytothe3SIOalgorithmsLabel.Visible = 'on';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_2.Visible = 'on';
                app.knowderivTextArea_2.Visible = 'on';
                
                app.TheseoptionsaredisabledforthisalgorithmLabel_4.Visible = 'on'; %simmetries
                app.TheseoptionsaredisabledforthisalgorithmLabel_3.Visible = 'off';%ORC
                app.TheseoptionsaredisabledforthisalgorithmLabel_2.Visible = 'off';%multiexp
                app.TheseoptionsaredisabledforthisalgorithmLabel.Visible = 'on';%decomposition
                
                drawnow
                
                %HIDE SETTINGS (Decomposition)
                app.OptionaldecompositionCheckBox.Visible = 'off';
                app.OnlydecomposemodelifthewholemodelistoolargeLabel.Visible = 'off';
                app.ForcedecompositionCheckBox.Visible = 'off';
                app.MaxNumStatesSubmEditField.Visible = 'off';
                app.MaximumnumberofstatesinthesubmodelsEditFieldLabel.Visible = 'off';
                app.MaxTimeAlloweOptEditField.Visible = 'off';
                app.MaximumtimeallowedforeveryoptimizationEditFieldLabel.Visible = 'off';
                app.IfoptimizationbaseddecompositionisusedLabel.Visible = 'off';
                app.submodelosTextArea.Visible = 'off';
                app.Example12pressenter34pressenter523Label.Visible = 'off';
                app.SubmodelsLabel.Visible = 'off';
                app.submodelUserCheckBox.Visible = 'off';
                app.Usesubmodelsspecifiedbytheuser1orfoundbyoptimization0Label.Visible = 'off';
                app.DoyouwanttodescomposethemodelLabel_2.Visible = 'off';
                app.DECOMPOSITIONOPTIONSapplyonlytotheFISPOalgorithmLabel.Visible = 'off';
                app.PathMeigoEditField.Visible = 'off';
                app.PathMeigoEditFieldLabel_2.Visible = 'off';
                            
                %app.KnownparametersCheckBox.Visible = 'off';
                app.MaxLieTimeEditField.Visible = 'off';
                app.SymbNumCheckBox.Visible = 'off';
                app.InitialconditionsCheckBox.Visible = 'off';
                app.Maxtimeforcalculating1LiederivativeEditFieldLabel.Visible = 'off';
                
                %HIDE SETTINGS (ORC)
                app.DelModelORCCheckBox.Visible = 'off';
                app.DisplaygraphicsCheckBox.Visible = 'off';
                app.NumWParEditField.Visible = 'off';
                app.NumberofworkersforparallelpoolLabel.Visible = 'off';
                app.ParalORCCheckBox.Visible = 'off';
                app.PARALLELOPTIONSLabel.Visible = 'off';
                app.MaxNumItEditField.Visible = 'off';
                app.MaxnumberofiterationsEditFieldLabel.Visible = 'off';
                app.MaxTimeEditField.Visible = 'off';
                app.MaxcomputationtimeforthelastiterationEditFieldLabel.Visible = 'off';
                app.AFFINEOPTIONSapplyonlytotheORCDFalgorithmLabel.Visible = 'off';
                
                %HIDE SETTINGS (LIESIMMETRIES)
                app.PathEditField.Visible = 'off';
                app.PathEditFieldLabel.Visible = 'off';
                app.existResultsCheckBox.Visible = 'off';
                app.solverLieCheckBox.Visible = 'off';
                app.MaxDegLieserEditField.Visible = 'off';
                app.MaximumdegreeofLieseriesEditFieldLabel.Visible = 'off';
                app.DegAnsatzEditField.Visible = 'off';
                app.DegreeofAnsatzpolynomialEditFieldLabel.Visible = 'off';
                app.TypeAnsatzEditField.Visible = 'off';
                app.TypeofAnsatzEditFieldLabel.Visible = 'off';
                app.Multivariate3Label.Visible = 'off';
                app.Partiallyvariate2Label.Visible = 'off';
                app.Univariate1Label.Visible = 'off';
                app.onlyforLie_SymmetryAutoReparLabel.Visible = 'off';
                app.LIESYMMETRIESREPARAMETERIZATIONOPTIONSLabel.Visible = 'off';


            elseif strcmp(value,'ORC DF') == 1
                app.OptAlg = 3; 
                
                app.MaxLieTimeEditField.Visible = 'on';
                app.SymbNumCheckBox.Visible = 'on';
                app.InitialconditionsCheckBox.Visible = 'on';
                app.Maxtimeforcalculating1LiederivativeEditFieldLabel.Visible = 'on';
                
                %SHOW SETTINGS (Multiexp)
                app.NumberofexperimentsEditField.Visible = 'on';
                app.NumberofexperimentsEditFieldLabel.Visible = 'on';
                app.knowderivTextArea.Visible = 'on';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel.Visible = 'on';
                app.knowderivTextArea.Visible = 'on';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel.Visible = 'on';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel_2.Visible = 'on';
                app.uknowCheckBox.Visible = 'on';
                app.knowMultiCheckBox.Visible = 'on';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel_4.Visible = 'on';
                app.wichicsMultiTextArea.Visible = 'on';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_7.Visible = 'on';
                app.icsMultiTextArea.Visible = 'on';
                app.InitialconditionsLabel_2.Visible = 'on';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_6.Visible = 'on';
                app.ICsMultCheckBox.Visible = 'on';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel.Visible = 'on';
                app.ExecuteSwitch.Visible = 'on';
                app.ExecutemultiexperimentanalysisLabel.Visible = 'on';
                app.MULTIEXPERIMENTOPTIONSapplytothe3SIOalgorithmsLabel.Visible = 'on';                
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_2.Visible = 'on';
                app.knowderivTextArea_2.Visible = 'on';
                
                %SHOW SETTINGS (ORC)
                app.DelModelORCCheckBox.Visible = 'on';
                app.DisplaygraphicsCheckBox.Visible = 'on';
                app.NumWParEditField.Visible = 'on';
                app.NumberofworkersforparallelpoolLabel.Visible = 'on';
                app.ParalORCCheckBox.Visible = 'on';
                app.PARALLELOPTIONSLabel.Visible = 'on';
                app.MaxNumItEditField.Visible = 'on';
                app.MaxnumberofiterationsEditFieldLabel.Visible = 'on';
                app.MaxTimeEditField.Visible = 'on';
                app.MaxcomputationtimeforthelastiterationEditFieldLabel.Visible = 'on';
                app.AFFINEOPTIONSapplyonlytotheORCDFalgorithmLabel.Visible = 'on';
                
                app.TheseoptionsaredisabledforthisalgorithmLabel_4.Visible = 'on';%symmetries
                app.TheseoptionsaredisabledforthisalgorithmLabel_3.Visible = 'off';%ORC
                app.TheseoptionsaredisabledforthisalgorithmLabel_2.Visible = 'off';%multiexp
                app.TheseoptionsaredisabledforthisalgorithmLabel.Visible = 'on';%decomposition
                
                drawnow
                
                %HIDE SETTINGS (Decomposition)
                app.OptionaldecompositionCheckBox.Visible = 'off';
                app.OnlydecomposemodelifthewholemodelistoolargeLabel.Visible = 'off';
                app.ForcedecompositionCheckBox.Visible = 'off';
                app.MaxNumStatesSubmEditField.Visible = 'off';
                app.MaximumnumberofstatesinthesubmodelsEditFieldLabel.Visible = 'off';
                app.MaxTimeAlloweOptEditField.Visible = 'off';
                app.MaximumtimeallowedforeveryoptimizationEditFieldLabel.Visible = 'off';
                app.IfoptimizationbaseddecompositionisusedLabel.Visible = 'off';
                app.submodelosTextArea.Visible = 'off';
                app.Example12pressenter34pressenter523Label.Visible = 'off';
                app.SubmodelsLabel.Visible = 'off';
                app.submodelUserCheckBox.Visible = 'off';
                app.Usesubmodelsspecifiedbytheuser1orfoundbyoptimization0Label.Visible = 'off';
                app.DoyouwanttodescomposethemodelLabel_2.Visible = 'off';
                app.DECOMPOSITIONOPTIONSapplyonlytotheFISPOalgorithmLabel.Visible = 'off';
                app.PathMeigoEditField.Visible = 'off';
                app.PathMeigoEditFieldLabel_2.Visible = 'off';
                
                %HIDE SETTINGS (LIESIMMETRIES)
                app.PathEditField.Visible = 'off';
                app.PathEditFieldLabel.Visible = 'off';
                app.existResultsCheckBox.Visible = 'off';
                app.solverLieCheckBox.Visible = 'off';
                app.MaxDegLieserEditField.Visible = 'off';
                app.MaximumdegreeofLieseriesEditFieldLabel.Visible = 'off';
                app.DegAnsatzEditField.Visible = 'off';
                app.DegreeofAnsatzpolynomialEditFieldLabel.Visible = 'off';
                app.TypeAnsatzEditField.Visible = 'off';
                app.TypeofAnsatzEditFieldLabel.Visible = 'off';
                app.Multivariate3Label.Visible = 'off';
                app.Partiallyvariate2Label.Visible = 'off';
                app.Univariate1Label.Visible = 'off';
                app.onlyforLie_SymmetryAutoReparLabel.Visible = 'off';
                app.LIESYMMETRIESREPARAMETERIZATIONOPTIONSLabel.Visible = 'off';
                
            elseif strcmp(value,'Lie_Symmetry') == 1
                
                app.OptAlg = 4;
                
                app.MaxLieTimeEditField.Visible = 'on';
                app.SymbNumCheckBox.Visible = 'on';
                app.InitialconditionsCheckBox.Visible = 'on';
                app.Maxtimeforcalculating1LiederivativeEditFieldLabel.Visible = 'on';
                
                %SHOW SETTINGS (LIESIMMETRIES)
                app.PathEditField.Visible = 'on';
                app.PathEditFieldLabel.Visible = 'on';
                app.existResultsCheckBox.Visible = 'on';
                app.solverLieCheckBox.Visible = 'on';
                app.MaxDegLieserEditField.Visible = 'on';
                app.MaximumdegreeofLieseriesEditFieldLabel.Visible = 'on';
                app.DegAnsatzEditField.Visible = 'on';
                app.DegreeofAnsatzpolynomialEditFieldLabel.Visible = 'on';
                app.TypeAnsatzEditField.Visible = 'on';
                app.TypeofAnsatzEditFieldLabel.Visible = 'on';
                app.Multivariate3Label.Visible = 'on';
                app.Partiallyvariate2Label.Visible = 'on';
                app.Univariate1Label.Visible = 'on';
                app.onlyforLie_SymmetryAutoReparLabel.Visible = 'on';
                app.LIESYMMETRIESREPARAMETERIZATIONOPTIONSLabel.Visible = 'on';
                
                app.TheseoptionsaredisabledforthisalgorithmLabel_4.Visible = 'off';%symmetries
                app.TheseoptionsaredisabledforthisalgorithmLabel_3.Visible = 'on';%ORC
                app.TheseoptionsaredisabledforthisalgorithmLabel_2.Visible = 'on';%multiexp
                app.TheseoptionsaredisabledforthisalgorithmLabel.Visible = 'on';%decomposition
                
                drawnow
                
                %HIDE SETTINGS (Decomposition)
                app.OptionaldecompositionCheckBox.Visible = 'off';
                app.OnlydecomposemodelifthewholemodelistoolargeLabel.Visible = 'off';
                app.ForcedecompositionCheckBox.Visible = 'off';
                app.MaxNumStatesSubmEditField.Visible = 'off';
                app.MaximumnumberofstatesinthesubmodelsEditFieldLabel.Visible = 'off';
                app.MaxTimeAlloweOptEditField.Visible = 'off';
                app.MaximumtimeallowedforeveryoptimizationEditFieldLabel.Visible = 'off';
                app.IfoptimizationbaseddecompositionisusedLabel.Visible = 'off';
                app.submodelosTextArea.Visible = 'off';
                app.Example12pressenter34pressenter523Label.Visible = 'off';
                app.SubmodelsLabel.Visible = 'off';
                app.submodelUserCheckBox.Visible = 'off';
                app.Usesubmodelsspecifiedbytheuser1orfoundbyoptimization0Label.Visible = 'off';
                app.DoyouwanttodescomposethemodelLabel_2.Visible = 'off';
                app.DECOMPOSITIONOPTIONSapplyonlytotheFISPOalgorithmLabel.Visible = 'off';
                app.PathMeigoEditField.Visible = 'off';
                app.PathMeigoEditFieldLabel_2.Visible = 'off';                

                %HIDE SETTINGS (ORC)
                app.DelModelORCCheckBox.Visible = 'off';
                app.DisplaygraphicsCheckBox.Visible = 'off';
                app.NumWParEditField.Visible = 'off';
                app.NumberofworkersforparallelpoolLabel.Visible = 'off';
                app.ParalORCCheckBox.Visible = 'off';
                app.PARALLELOPTIONSLabel.Visible = 'off';
                app.MaxNumItEditField.Visible = 'off';
                app.MaxnumberofiterationsEditFieldLabel.Visible = 'off';
                app.MaxTimeEditField.Visible = 'off';
                app.MaxcomputationtimeforthelastiterationEditFieldLabel.Visible = 'off';
                app.AFFINEOPTIONSapplyonlytotheORCDFalgorithmLabel.Visible = 'off';                
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_2.Visible = 'off';
                
                %HIDE SETTINGS (Multiexp)
                app.NumberofexperimentsEditField.Visible = 'off';
                app.NumberofexperimentsEditFieldLabel.Visible = 'off';
                app.knowderivTextArea.Visible = 'off';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel.Visible = 'off';
                app.knowderivTextArea.Visible = 'off';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel.Visible = 'off';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel_2.Visible = 'off';
                app.uknowCheckBox.Visible = 'off';
                app.knowMultiCheckBox.Visible = 'off';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel_4.Visible = 'off';
                app.wichicsMultiTextArea.Visible = 'off';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_7.Visible = 'off';
                app.icsMultiTextArea.Visible = 'off';
                app.InitialconditionsLabel_2.Visible = 'off';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_6.Visible = 'off';
                app.ICsMultCheckBox.Visible = 'off';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel.Visible = 'off';
                app.ExecuteSwitch.Visible = 'off';
                app.ExecutemultiexperimentanalysisLabel.Visible = 'off';
                app.MULTIEXPERIMENTOPTIONSapplytothe3SIOalgorithmsLabel.Visible = 'off';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_2.Visible = 'off';
                app.knowderivTextArea_2.Visible = 'off';
                
            elseif strcmp(value,'AutoRepar') == 1
                app.OptAlg = 5;   
                
                app.MaxLieTimeEditField.Visible = 'on';
                app.SymbNumCheckBox.Visible = 'on';
                app.InitialconditionsCheckBox.Visible = 'on';
                app.Maxtimeforcalculating1LiederivativeEditFieldLabel.Visible = 'on';
                
                %SHOW SETTINGS (LIESIMMETRIES)
                app.PathEditField.Visible = 'on';
                app.PathEditFieldLabel.Visible = 'on';
                app.existResultsCheckBox.Visible = 'on';
                app.solverLieCheckBox.Visible = 'on';
                app.MaxDegLieserEditField.Visible = 'on';
                app.MaximumdegreeofLieseriesEditFieldLabel.Visible = 'on';
                app.DegAnsatzEditField.Visible = 'on';
                app.DegreeofAnsatzpolynomialEditFieldLabel.Visible = 'on';
                app.TypeAnsatzEditField.Visible = 'on';
                app.TypeofAnsatzEditFieldLabel.Visible = 'on';
                app.Multivariate3Label.Visible = 'on';
                app.Partiallyvariate2Label.Visible = 'on';
                app.Univariate1Label.Visible = 'on';
                app.onlyforLie_SymmetryAutoReparLabel.Visible = 'on';
                app.LIESYMMETRIESREPARAMETERIZATIONOPTIONSLabel.Visible = 'on';
                
                app.TheseoptionsaredisabledforthisalgorithmLabel_4.Visible = 'off';%symmetries
                app.TheseoptionsaredisabledforthisalgorithmLabel_3.Visible = 'on';%ORC
                app.TheseoptionsaredisabledforthisalgorithmLabel_2.Visible = 'on';%multiexp
                app.TheseoptionsaredisabledforthisalgorithmLabel.Visible = 'on';%decomposition
                
                drawnow
                
                %HIDE SETTINGS (Decomposition)
                app.OptionaldecompositionCheckBox.Visible = 'off';
                app.OnlydecomposemodelifthewholemodelistoolargeLabel.Visible = 'off';
                app.ForcedecompositionCheckBox.Visible = 'off';
                app.MaxNumStatesSubmEditField.Visible = 'off';
                app.MaximumnumberofstatesinthesubmodelsEditFieldLabel.Visible = 'off';
                app.MaxTimeAlloweOptEditField.Visible = 'off';
                app.MaximumtimeallowedforeveryoptimizationEditFieldLabel.Visible = 'off';
                app.IfoptimizationbaseddecompositionisusedLabel.Visible = 'off';
                app.submodelosTextArea.Visible = 'off';
                app.Example12pressenter34pressenter523Label.Visible = 'off';
                app.SubmodelsLabel.Visible = 'off';
                app.submodelUserCheckBox.Visible = 'off';
                app.Usesubmodelsspecifiedbytheuser1orfoundbyoptimization0Label.Visible = 'off';
                app.DoyouwanttodescomposethemodelLabel_2.Visible = 'off';
                app.DECOMPOSITIONOPTIONSapplyonlytotheFISPOalgorithmLabel.Visible = 'off';
                app.PathMeigoEditField.Visible = 'off';
                app.PathMeigoEditFieldLabel_2.Visible = 'off';                

                %HIDE SETTINGS (ORC)
                app.DelModelORCCheckBox.Visible = 'off';
                app.DisplaygraphicsCheckBox.Visible = 'off';
                app.NumWParEditField.Visible = 'off';
                app.NumberofworkersforparallelpoolLabel.Visible = 'off';
                app.ParalORCCheckBox.Visible = 'off';
                app.PARALLELOPTIONSLabel.Visible = 'off';
                app.MaxNumItEditField.Visible = 'off';
                app.MaxnumberofiterationsEditFieldLabel.Visible = 'off';
                app.MaxTimeEditField.Visible = 'off';
                app.MaxcomputationtimeforthelastiterationEditFieldLabel.Visible = 'off';
                app.AFFINEOPTIONSapplyonlytotheORCDFalgorithmLabel.Visible = 'off';                   
                
                %HIDE SETTINGS (Multiexp)
                app.NumberofexperimentsEditField.Visible = 'off';
                app.NumberofexperimentsEditFieldLabel.Visible = 'off';
                app.knowderivTextArea.Visible = 'off';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel.Visible = 'off';
                app.knowderivTextArea.Visible = 'off';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel.Visible = 'off';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel_2.Visible = 'off';
                app.uknowCheckBox.Visible = 'off';
                app.knowMultiCheckBox.Visible = 'off';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel_4.Visible = 'off';
                app.wichicsMultiTextArea.Visible = 'off';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_7.Visible = 'off';
                app.icsMultiTextArea.Visible = 'off';
                app.InitialconditionsLabel_2.Visible = 'off';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_6.Visible = 'off';
                app.ICsMultCheckBox.Visible = 'off';
                app.SetmanuallytheinitialconditionsforeachexperimentLabel.Visible = 'off';
                app.ExecuteSwitch.Visible = 'off';
                app.ExecutemultiexperimentanalysisLabel.Visible = 'off';
                app.MULTIEXPERIMENTOPTIONSapplytothe3SIOalgorithmsLabel.Visible = 'off';
                app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_2.Visible = 'off';
                app.knowderivTextArea_2.Visible = 'off';
            end
            
        end

        % Close request function: SG
        function SGCloseRequest(app, event)
            uiconfirm(app.SG,'Are you sure you wanto to close STRIKE-GOLDD?','Confirm Close', ...
            'CloseFcn',@app.mycallback);
            
        end

        % Value changed function: SymbNumCheckBox
        function SymbNumCheckBoxValueChanged(app, event)
            
            %%% by default
            % app.SymbNumCheckBox.Value = 0
            
            app.RankNumeric = app.SymbNumCheckBox.Value;
            
        end

        % Value changed function: InitialconditionsCheckBox
        function InitialconditionsCheckBoxValueChanged(app, event)
            
            %%% by default
            % app.InitialconditionsCheckBox.Value = 0
            
            app.ICs = app.InitialconditionsCheckBox.Value;
            
        end

        % Value changed function: MaxLieTimeEditField
        function MaxLieTimeEditFieldValueChanged(app, event)
            
            %%% by default
            % app.MaxLieTimeEditField.Value = 100
            
            app.maxLieTime = app.MaxLieTimeEditField.Value;
            
        end

        % Callback function
        function NuEditFieldValueChanged(app, event)
            
            %%% by default 
            % app.NuEditField.Value = inf;
            
            app.derU = app.NuEditField.Value;
            
        end

        % Callback function
        function NwEditFieldValueChanged(app, event)
            
            %%% by default 
            % app.NwEditField.Value = 1;
            
            app.derW = app.NwEditField.Value;
            
        end

        % Value changed function: PathMeigoEditField
        function PathMeigoEditFieldValueChanged(app, event)
            
            %%% by default
            % app.PathMeigoEditField.Value = C:\Users\xabor\Downloads\strike-goldd-dev\MEIGO;            
            
            app.PathMeigo = app.PathMeigoEditField.Value;
            
        end

        % Value changed function: ForcedecompositionCheckBox
        function ForcedecompositionCheckBoxValueChanged(app, event)
            
            %%% by default 
            % app.ForcedecompositionCheckBox.Value = 0;
            
            app.ForceDecomp = app.ForcedecompositionCheckBox.Value;
            
        end

        % Value changed function: OptionaldecompositionCheckBox
        function OptionaldecompositionCheckBoxValueChanged(app, event)
            
            %%% by default 
            % app.OptionaldecompositionCheckBox.Value = 0;     
            
            app.optDecomp = app.OptionaldecompositionCheckBox.Value;
            
            
        end

        % Value changed function: submodelUserCheckBox
        function submodelUserCheckBoxValueChanged(app, event)
            
            %%% by default
            % app.submodelUserCheckBox.Value = 0            
            
            app.userSub = app.submodelUserCheckBox.Value;
            
        end

        % Value changed function: MaxTimeEditField
        function MaxTimeEditFieldValueChanged(app, event)

            %%% by default
            % app.MaxTimeEditField.Value = 1000  
            
            app.MaxTimORC = app.MaxTimeEditField.Value;
            
        end

        % Value changed function: MaxNumItEditField
        function MaxNumItEditFieldValueChanged(app, event)
            
            %%% by default
            % app.MaxNumItEditField.Value = 4              
            
            app.MaxItORC = app.MaxNumItEditField.Value;
            
        end

        % Value changed function: ParalORCCheckBox
        function ParalORCCheckBoxValueChanged(app, event)
            
            %%% by default
            % app.MaxNumItEditField.Value = 0               
                                  
            app.ParalORC = app.ParalORCCheckBox.Value;
            
        end

        % Value changed function: NumWParEditField
        function NumWParEditFieldValueChanged(app, event)
            
            %%% by default
            % app.NumWParEditField.Value = 4                
                        
            app.NumWorORC = app.NumWParEditField.Value;
            
        end

        % Value changed function: DisplaygraphicsCheckBox
        function DisplaygraphicsCheckBoxValueChanged(app, event)

            %%% by default
            % app.DisplaygraphicsCheckBox.Value = 1  

            app.DGraphORC = app.DisplaygraphicsCheckBox.Value;
            
        end

        % Value changed function: DelModelORCCheckBox
        function DelModelORCCheckBoxValueChanged(app, event)
            
            %%% by default
            % app.DelModelORCCheckBox.Value = 1  
            
            app.DelModORC = app.DelModelORCCheckBox.Value;
            
        end

        % Value changed function: ExecuteSwitch
        function ExecuteSwitchValueChanged(app, event)
            
            %%% by default
            % app.ExecuteSwitch.Value = 0              
            
            app.multiExp = app.ExecuteSwitch.Value;
        end

        % Value changed function: TypeAnsatzEditField
        function TypeAnsatzEditFieldValueChanged(app, event)
            
            %%% by default
            % app.TypeAnsatzEditField.Value = 2             
            
            app.TyAnsLie = app.TypeAnsatzEditField.Value;
            
        end

        % Value changed function: DegAnsatzEditField
        function DegAnsatzEditFieldValueChanged(app, event)
            
            %%% by default
            % app.DegAnsatzEditField.Value = 2             
            
            app.DegAnsLie = app.DegAnsatzEditField.Value;
            
        end

        % Value changed function: MaxDegLieserEditField
        function MaxDegLieserEditFieldValueChanged(app, event)
            
            %%% by default
            % app.MaxDegLieserEditField.Value = 4              
            
            app.MaxDegLie = app.MaxDegLieserEditField.Value;
            
        end

        % Value changed function: solverLieCheckBox
        function solverLieCheckBoxValueChanged(app, event)
            
            %%% by default
            % app.solverLieCheckBox.Value = 1              
            
            app.solverLie = app.solverLieCheckBox.Value;
            
        end

        % Value changed function: existResultsCheckBox
        function existResultsCheckBoxValueChanged(app, event)
            
            %%% by default
            % app.existResultsCheckBox.Value = 0            
                       
            app.resultLie = app.existResultsCheckBox.Value;
            
        end

        % Value changed function: PathEditField
        function PathEditFieldValueChanged(app, event)
            
            %%% by default, folder: results
            % app.existResultsCheckBox.Value = id_results_C2M_11-May-2022            
            
            app.pathResLie = app.PathEditField.Value;
            
        end

        % Value changed function: MaxTimeAlloweOptEditField
        function MaxTimeAlloweOptEditFieldValueChanged(app, event)
            
            %%% by default
            % app.MaxTimeAlloweOptEditField.Value = 30             
            
            app.maxTimAl = app.MaxTimeAlloweOptEditField.Value;
            
        end

        % Value changed function: MaxNumStatesSubmEditField
        function MaxNumStatesSubmEditFieldValueChanged(app, event)
            
            %%% by default
            % app.MaxNumStatesSubmEditField.Value = 6         
            
            app.maxStates = app.MaxNumStatesSubmEditField.Value;
     
        end

        % Value changed function: submodelosTextArea
        function submodelosTextAreaValueChanged(app, event)
            
            %%% by default
            % app.submodelosTextArea.Value = [1 2];[3 4];[5 2 3]
            
            app.submod = app.submodelosTextArea.Value;
        end

        % Value changed function: ICsMultCheckBox
        function ICsMultCheckBoxValueChanged(app, event)
            
            %%% by default
            % app.ICsMultCheckBox.Value = 0            
            
            app.ICsMulti = app.ICsMultCheckBox.Value;
            
        end

        % Value changed function: knowMultiCheckBox
        function knowMultiCheckBoxValueChanged(app, event)

            %%% by default
            % app.knowMultiCheckBox.Value = 0     

            app.knowMulti = app.knowMultiCheckBox.Value;
            
        end

        % Value changed function: uknowCheckBox
        function uknowCheckBoxValueChanged(app, event)
            
            %%% by default
            % app.uknowCheckBox.Value = 0              
            
            app.uknowMulti = app.uknowCheckBox.Value;
            
        end

        % Callback function
        function ModelnameEditFieldValueChanged(app, event)
            %app.Lamp.Color = 'red';
        end

        % Value changed function: NumberofexperimentsEditField
        function NumberofexperimentsEditFieldValueChanged(app, event)
             
            %%% by default
            % app.NumberofexperimentsEditField.Value = 0            
            
            app.numExpMulti = app.NumberofexperimentsEditField.Value;
            
            
        end

        % Value changed function: knowderivTextArea
        function knowderivTextAreaValueChanged(app, event)
            
            %%% by default
            % app.knowderivTextArea.Value = [0 1]            
            
            app.knDerMulti = app.knowderivTextArea.Value;
            
        end

        % Value changed function: knowderivTextArea_2
        function knowderivTextArea_2ValueChanged(app, event)
            
            %%% by default
            % app.knowderivTextArea_2.Value = [0 1] 
            
            app.uknDerMulti = app.knowderivTextArea_2.Value;
            
        end

        % Value changed function: icsMultiTextArea
        function icsMultiTextAreaValueChanged(app, event)
 
            
            %%% by default
            % app.icsMultiTextArea.Value = [1,0,0,1,0,1,0,0,0];[1,0,0,1,0,1,0,0,0]
            
            app.icsMulti = app.icsMultiTextArea.Value;
            
        end

        % Value changed function: wichicsMultiTextArea
        function wichicsMultiTextAreaValueChanged(app, event)
            
            %%% by default
            % app.wichicsMultiTextArea.Value = [0,1,1,0,1,0,1,1,1];[0,1,1,0,1,0,1,1,1]            
            
            
            app.WicsMulti = app.wichicsMultiTextArea.Value;
            
        end

        % Value changed function: KnownparametersCheckBox
        function KnownparametersCheckBoxValueChanged(app, event)
            
            %%% by default
            % app.KnownparametersCheckBox.Value = 0             
            
            value = app.KnownparametersCheckBox.Value;
            
            if value == 1
                prevPars
            else 
                SGfolder = cd;
                cd 'functions\aux_APP'
                fid = fopen('prevIdPars.m', 'w');
                fprintf(fid,'function prev_pars = prevIdPars()\n');
                fprintf(fid,'prev_pars = [];\n');
                fprintf(fid,'end');
                fclose(fid);
                cd(SGfolder)
            end
            
        end

        % Value changed function: nonzeroDerU
        function nonzeroDerUValueChanged(app, event)
            
            %%% by default
            % app.nonzeroDerU.Value = inf
            
            app.derU = app.nonzeroDerU.Value;            
            
            
        end

        % Value changed function: nonzeroDerW
        function nonzeroDerWValueChanged(app, event)
            
            %%% by default
            % app.app.nonzeroDerW.Value = 2
            
            app.derW = app.nonzeroDerW.Value;  
            
        end

        % Callback function
        function CloseappMenuSelected(app, event)
            close(app.SG)
        end

        % Callback function
        function OpenmodelsfolderMenuSelected(app, event)

            file = uigetfile;
            
        end

        % Callback function
        function CreatenewmodelMenuSelected(app, event)
            newmodel
        end

        % Button pushed function: CloseappButton
        function CloseappButtonPushed(app, event)
            close(app.SG)
        end

        % Button pushed function: SaveoptionsfileButton
        function SaveoptionsfileButtonPushed(app, event)
            %Esta función va a guardar todas las opciones en un archivo que
            %se va a llamar options_'x', donde la x es el nombre del modelo
            %Los pasos son los mismos que cuando se pulsa el botón Run y
            %para escribir se usa los paso de overwrite_options, dado que
            %hacen lo mismo. 
            
            modelname = app.ModelnameEditField;
            value1 = app.AlgorithmDropDown.Value;
            if strcmp(value1,'FISPO') == 1
                app.OptAlg = 1;
            elseif strcmp(value1,'Prob_Obs_Test') == 1
                app.OptAlg = 2;
            elseif strcmp(value1,'ORC DF') == 1
                app.OptAlg = 3; 
            elseif strcmp(value1,'Lie_Symmetry') == 1
                app.OptAlg = 4;
            elseif strcmp(value1,'AutoRepar') == 1
                app.OptAlg = 5;            
            end          
            
            app.RankNumeric = app.SymbNumCheckBox.Value;
            app.ICs = app.InitialconditionsCheckBox.Value;
            app.maxLieTime = app.MaxLieTimeEditField.Value;
            app.derU = app.nonzeroDerU.Value;
            app.derW = app.nonzeroDerW.Value;  
            app.PathMeigo = app.PathMeigoEditField.Value;
            app.ForceDecomp = app.ForcedecompositionCheckBox.Value;
            app.optDecomp = app.OptionaldecompositionCheckBox.Value;
            app.maxTimAl = app.MaxTimeAlloweOptEditField.Value;
            app.maxStates = app.MaxNumStatesSubmEditField.Value;
            app.userSub = app.submodelUserCheckBox.Value;
            app.MaxTimORC = app.MaxTimeEditField.Value;
            app.MaxItORC = app.MaxNumItEditField.Value;
            app.ParalORC = app.ParalORCCheckBox.Value;
            app.NumWorORC = app.NumWParEditField.Value;
            app.DGraphORC = app.DisplaygraphicsCheckBox.Value;
            app.DelModORC = app.DelModelORCCheckBox.Value;
            app.multiExp = app.ExecuteSwitch.Value;
            app.TyAnsLie = app.TypeAnsatzEditField.Value;
            app.DegAnsLie = app.DegAnsatzEditField.Value;
            app.MaxDegLie = app.MaxDegLieserEditField.Value;
            app.solverLie = app.solverLieCheckBox.Value;
            app.resultLie = app.existResultsCheckBox.Value;
            app.pathResLie = app.PathEditField.Value;
            app.submod = app.submodelosTextArea.Value;
            app.ICsMulti = app.ICsMultCheckBox.Value;
            app.knowMulti = app.knowMultiCheckBox.Value;
            app.uknowMulti = app.uknowCheckBox.Value;
            app.numExpMulti = app.NumberofexperimentsEditField.Value;
            app.knDerMulti = app.knowderivTextArea.Value;
            app.uknDerMulti = app.knowderivTextArea_2.Value;
            app.icsMulti = app.icsMultiTextArea.Value;
            app.WicsMulti = app.wichicsMultiTextArea.Value;
            app.pars = prevIdPars();
            app.multiExp = str2double(app.multiExp);
            b = app.knDerMulti;
            j=1;
            index = [];
            for i = 1:length(b)
                if b(i) == ""
                    index(j) = i;
                    j = j+1;
                end
            end
            for i = 1:length(index)
                b(index(i)) = [];
            end
            bb = erase(b,{'[',']',' ', ','});        
            for i = 1:length(bb)
                v = zeros(length(bb(i)));
                for k = 1:length(bb{i})
                    number = str2double(bb{i}(k));
                    v(k) = number;
                end
                vv{i} = v;
                clear v
            end       
            % cell array -> array
            arrayV = zeros(length(vv),length(vv{1}));
            for i = 1:length(vv)
                arrayV(i,:) = vv{i};
            end
            
            app.knDerMulti = arrayV;  
            
            clear b bb j index v vv arrayV
            
            %%% ¡unknown input derivatives Multi-experiments!
            b = app.uknDerMulti;
            j=1;
            index = [];
            for i = 1:length(b)
                if b(i) == ""
                    index(j) = i;
                    j = j+1;
                end
            end
            for i = 1:length(index)
                b(index(i)) = [];
            end
            bb = erase(b,{'[',']',' ', ','});       
            for i = 1:length(bb)
                v = zeros(length(bb(i)));
                for k = 1:length(bb{i})
                    number = str2double(bb{i}(k));
                    v(k) = number;
                end
                vv{i} = v;
                clear v
            end       
            arrayV = zeros(length(vv),length(vv{1}));
            for i = 1:length(vv)
                arrayV(i,:) = vv{i};
            end
            app.uknDerMulti = arrayV;  
            
            
            clear b bb j index v vv arrayV
            
            
            %%% ¡initial conditions Multi-experiments!
            b = app.icsMulti;
            j=1;
            index = [];
            for i = 1:length(b)
                if b(i) == ""
                    index(j) = i;
                    j = j+1;
                end
            end
            for i = 1:length(index)
                b(index(i)) = [];
            end
            bb = erase(b,{'[',']',' ', ','});         
            for i = 1:length(bb)
                v = zeros(length(bb(i)));
                for k = 1:length(bb{i})
                    number = str2double(bb{i}(k));
                    v(k) = number;
                end
                vv{i} = v;
                clear v
            end    
            arrayV = zeros(length(vv),length(vv{1}));
            for i = 1:length(vv)
                arrayV(i,:) = vv{i};
            end
            %Transpose
            app.icsMulti = arrayV.';     
            
            clear b bb j index v vv arrayV           
           
            %%% ¡which initial conditions are known Multi-experiments!
            b = app.WicsMulti;
            j=1;
            index = [];
            for i = 1:length(b)
                if b(i) == ""
                    index(j) = i;
                    j = j+1;
                end
            end
            for i = 1:length(index)
                b(index(i)) = [];
            end
            bb = erase(b,{'[',']',' ', ','});       
            for i = 1:length(bb)
                v = zeros(length(bb(i)));
                for k = 1:length(bb{i})
                    number = str2double(bb{i}(k));
                    v(k) = number;
                end
                vv{i} = v;
                clear v
            end       
            arrayV = zeros(length(vv),length(vv{1}));
            for i = 1:length(vv)
                arrayV(i,:) = vv{i};
            end
            app.WicsMulti = arrayV.';           

            clear b bb j index v vv arrayV 
            
            %% Derivadas de las funciones de entrada
            b = app.derU{1};
            
            j=1;
            index = [];
            
            bb = erase(b,{'[',']'});    
            b = bb;
            
            %Identifico los espacios para saber donde se separan los elementos (index)
            for i = 1:length(b)
                if b(i) == ' '
                    index(j) = i;
                    j = j+1;
                elseif b(i) ==  ','
                    index(j) = i;
                    j = j+1;        
                end
            end
            
            if isempty(index)
                app.derU = str2double(b);
            else    
                matrix1(1) = str2double( b( 1: index(1)-1) );
               
                for i = 1:length(index)-1
                    
                    %1º posición de b: index(1)+1
                    %2º posición de b: index(2)-1
                    pos1 = index(i)+1;
                    pos2 = index(i+1)-1;
                
                    matrix1(i+1) = str2double(b(pos1:pos2));
                
                    %b(index(i)) = [];
                end
                
                matrix1(length(index)+1) = str2double(b(index(end):end));
                app.derU = matrix1;
            end
            
            
            %%%-------------------------------------------------------
            
            clear b j index matrix1 pos1 pos2 bb
            
            %%%-------------------------------------------------------
            
            %%% ¡numbers nonzero derivatives w!
            b = app.derW{1};
            
            j=1;
            index = [];
            
            bb = erase(b,{'[',']'});    
            b = bb;
            
            %Identifico los espacios para saber donde se separan los elementos (index)
            for i = 1:length(b)
                if b(i) == ' '
                    index(j) = i;
                    j = j+1;
                elseif b(i) ==  ','
                    index(j) = i;
                    j = j+1;        
                end
            end
            
            if isempty(index)
                app.derW = str2double(b);
                
            else    
                matrix1(1) = str2double( b( 1: index(1)-1) );
               
                for i = 1:length(index)-1
                    
                    %1º posición de b: index(1)+1
                    %2º posición de b: index(2)-1
                    pos1 = index(i)+1;
                    pos2 = index(i+1)-1;
                
                    matrix1(i+1) = str2double(b(pos1:pos2));
                
                    %b(index(i)) = [];
                end
                
                matrix1(length(index)+1) = str2double(b(index(end):end));
                app.derW = matrix1;
            end

            
            paths.meigo = app.PathMeigo;
            paths.models    = strcat(pwd,filesep,'models');
            paths.results   = strcat(pwd,filesep,'results');
            paths.functions = strcat(pwd,filesep,'functions');
            
            fid = fopen(sprintf('options_%s.m',modelname),'w');            
            fprintf(fid,'function [modelname,paths,opts,submodels,prev_ident_pars]=options_%s()\n',modelname);          
            fprintf(fid,'modelname = "%s";\n',modelname);
            fprintf(fid,'modelname = convertStringsToChars(modelname);\n');
            fprintf(fid,'opts.algorithm = %d;\n',app.OptAlg);
            fprintf(fid,'opts.maxLietime = %d;\n',app.maxLieTime);
            fprintf(fid,'opts.nnzDerU    = [');
            fprintf(fid,repmat('%d ', 1, length(app.derU)), app.derU);
            fprintf(fid,'];\n');
            fprintf(fid,'opts.nnzDerW    = [');
            fprintf(fid,repmat('%d ', 1, length(app.derW)), app.derW);
            fprintf(fid,'];\n');
            fprintf(fid,'opts.numeric    = %i;\n',app.RankNumeric);
            fprintf(fid,'opts.replaceICs = %i;\n', app.ICs);
            fprintf(fid,'prev_ident_pars = prevIdPars();\n');
            fprintf(fid, 'opts.affine_tStage = %d;\n', app.MaxTimORC);
            fprintf(fid, 'opts.affine_kmax = %d;\n', app.MaxItORC); 
            fprintf(fid, 'opts.affine_parallel = %i;\n', app.ParalORC);
            fprintf(fid, 'opts.affine_workers = %i;\n', app.NumWorORC);
            fprintf(fid, 'opts.affine_graphics = %i;\n', app.DGraphORC);
            fprintf(fid, 'opts.affine_delete_model = %i;\n', app.DelModORC);            
            fprintf(fid, 'opts.forcedecomp = %i;\n', app.ForceDecomp);
            fprintf(fid, 'opts.decomp = %i;\n',app.optDecomp);
            fprintf(fid, 'opts.maxOpttime = %d;\n', app.maxTimAl);
            fprintf(fid, 'opts.maxstates = %i;\n', app.maxStates);
            fprintf(fid, 'opts.decomp_user = %i;\n', app.userSub);
            for i = 1:length(app.submod)
                fprintf(fid, 'submodels{%i} = [',i);
                fprintf(fid, repmat('%d ', 1, length(app.submod(i))), app.submod{i});
                fprintf(fid,'];\n');
            end
            fprintf(fid, 'opts.multiexp = %i;\n', app.multiExp);    
            fprintf(fid, 'opts.multiexp_numexp = %i;\n', app.numExpMulti);     
            fprintf(fid, 'opts.multiexp_user_nnzDerU = %i;\n', app.knowMulti);
            fprintf(fid, 'opts.multiexp_nnzDerU = [');
            fprintf(fid,repmat('%d ', 1, length(app.knDerMulti)), app.knDerMulti);
            fprintf(fid, '];\n');
            fprintf(fid, 'opts.multiexp_user_nnzDerW = %i;\n', app.uknowMulti);
            fprintf(fid, 'opts.multiexp_nnzDerW = [');
            fprintf(fid,repmat('%d ', 1, length(app.uknDerMulti)), app.uknDerMulti);
            fprintf(fid, '];\n'); 
            fprintf(fid, 'opts.multiexp_user_ics = %i;\n', app.ICsMulti);
            [row,columns] = size(app.icsMulti);
            fprintf(fid, 'opts.multiexp_ics = [');
            for i = 1:columns-1
                fprintf(fid,'transpose(['); 
                fprintf(fid, repmat('%d ', 1, length(app.icsMulti(:,i))), app.icsMulti(:,i));
                fprintf(fid,']),');
            end            
            fprintf(fid,'transpose([');
            fprintf(fid, repmat('%d ', 1, length(app.icsMulti(:,end))), app.icsMulti(:,end));
            fprintf(fid,'])];\n');
            [row,columns] = size(app.WicsMulti);
            fprintf(fid, 'opts.multiexp_known_ics = [');
            for i = 1:columns-1
                fprintf(fid,'transpose(['); 
                fprintf(fid, repmat('%d ', 1, length(app.WicsMulti(:,i))), app.WicsMulti(:,i));
                fprintf(fid,']),');
            end            
            fprintf(fid,'transpose([');
            fprintf(fid, repmat('%d ', 1, length(app.WicsMulti(:,end))), app.WicsMulti(:,end));
            fprintf(fid,'])];\n');
            fprintf(fid, 'opts.ansatz = %i; \n', app.TyAnsLie);
            fprintf(fid, 'opts.degree = %i; \n', app.DegAnsLie); 
            fprintf(fid, 'opts.tmax = %i;\n', app.MaxDegLie); 
            fprintf(fid, 'opts.ode_n  = %i;\n', app.solverLie); 
            fprintf(fid, 'opts.use_existing_results = %i;\n', app.resultLie); 
            fprintf(fid, 'opts.results_file = "%s";\n', app.pathResLie); 
            fprintf(fid, 'opts.results_file = convertStringsToChars(opts.results_file);\n');
            fprintf(fid,'paths.meigo = "%s";\n',app.PathMeigo);
            fprintf(fid,'paths.meigo = convertStringsToChars(paths.meigo);\n');
            fprintf(fid,'paths.models = "%s";\n',paths.models);
            fprintf(fid,'paths.models = convertStringsToChars(paths.models);\n');
            fprintf(fid,'paths.results = "%s";\n',paths.results);
            fprintf(fid,'paths.results = convertStringsToChars(paths.results);\n');
            fprintf(fid,'paths.functions = "%s";\n',paths.functions);
            fprintf(fid,'paths.functions = convertStringsToChars(paths.functions);\n');             
            fclose(fid);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButtoon = app.ButtonGroup.SelectedObject;
            app.selectedButton = selectedButtoon.Text;
            if strcmp(app.selectedButton,'USE EXISTING MODEL')
                
                %Por si se añadió un nuevo modelo se vuelve a revisar la
                %carpeta y a añadir todos los modelos que haya en ella
                
                %%% Nuevo modelo a la LISTA DESPLEGABLE %%%%%%%
                SGfolder = cd;
                cd models
                
                Files=dir('*.mat*');
                for k=1:length(Files)
                   FileNames{k}=Files(k).name;
                end
                            
                for i = 1:length(FileNames) 
                    app.namefiles{i} = erase(FileNames{i},'.mat');
                end
                
                cd(SGfolder)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                app.ModelnameDropDown.Visible = 'on';
                
            elseif strcmp(app.selectedButton,'CREATE NEW MODEL')
                
                newmodel             
                
                app.ModelnameDropDown.Visible = 'off';

             end
            
        end

        % Drop down opening function: ModelnameDropDown
        function ModelnameDropDownOpening(app, event)
            app.ModelnameDropDown.Items = app.namefiles;
        end

        % Value changed function: ModelnameDropDown
        function ModelnameDropDownValueChanged(app, event)
            value = app.ModelnameDropDown.Value;
            app.ModelnameEditField = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SG = uifigure('Visible', 'off');
            app.SG.Name = 'STRIKE-GOLDD';
            app.SG.CloseRequestFcn = createCallbackFcn(app, @SGCloseRequest, true);
            app.STRIKEGOLDDPanel = uipanel(app.SG);
            app.STRIKEGOLDDPanel.Title = 'STRIKE GOLDD';
            app.TabGroup = uitabgroup(app.SG);
            app.IDENTIFIABILITYOPTIONSTab = uitab(app.TabGroup);
            app.IDENTIFIABILITYOPTIONSTab.Title = 'IDENTIFIABILITY OPTIONS';
            app.SymbNumCheckBox = uicheckbox(app.IDENTIFIABILITYOPTIONSTab);
            app.SymbNumCheckBox.ValueChangedFcn = createCallbackFcn(app, @SymbNumCheckBoxValueChanged, true);
            app.SymbNumCheckBox.Text = 'Calculate rank numerically';
            app.InitialconditionsCheckBox = uicheckbox(app.IDENTIFIABILITYOPTIONSTab);
            app.InitialconditionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @InitialconditionsCheckBoxValueChanged, true);
            app.InitialconditionsCheckBox.Text = 'Initial conditions';
            app.Maxtimeforcalculating1LiederivativeEditFieldLabel = uilabel(app.IDENTIFIABILITYOPTIONSTab);
            app.Maxtimeforcalculating1LiederivativeEditFieldLabel.Text = 'Max. time for calculating 1 Lie derivative ';
            app.MaxLieTimeEditField = uieditfield(app.IDENTIFIABILITYOPTIONSTab, 'numeric');
            app.MaxLieTimeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxLieTimeEditFieldValueChanged, true);
            app.MaxLieTimeEditField.Value = 100;
            app.MainstructuralidentifiabilityandobservabilityoptionsLabel = uilabel(app.IDENTIFIABILITYOPTIONSTab);
            app.MainstructuralidentifiabilityandobservabilityoptionsLabel.Text = 'Main structural identifiability and observability options';
            app.KnownparametersCheckBox = uicheckbox(app.IDENTIFIABILITYOPTIONSTab);
            app.KnownparametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @KnownparametersCheckBoxValueChanged, true);
            app.KnownparametersCheckBox.Text = 'Known parameters';
            app.NumberofnonzeroderivativesofuLabel = uilabel(app.IDENTIFIABILITYOPTIONSTab);
            app.NumberofnonzeroderivativesofuLabel.Text = 'Number of nonzero derivatives of u';
            app.nonzeroDerU = uitextarea(app.IDENTIFIABILITYOPTIONSTab);
            app.nonzeroDerU.ValueChangedFcn = createCallbackFcn(app, @nonzeroDerUValueChanged, true);
            app.nonzeroDerU.Value = {'2'};
            app.NumberofnonzeroderivativesofwLabel = uilabel(app.IDENTIFIABILITYOPTIONSTab);
            app.NumberofnonzeroderivativesofwLabel.Text = 'Number of nonzero derivatives of w';
            app.nonzeroDerW = uitextarea(app.IDENTIFIABILITYOPTIONSTab);
            app.nonzeroDerW.ValueChangedFcn = createCallbackFcn(app, @nonzeroDerWValueChanged, true);
            app.nonzeroDerW.Value = {'2'};
            app.DECOMPOSITIONTab = uitab(app.TabGroup);
            app.DECOMPOSITIONTab.Title = 'DECOMPOSITION';
            app.PathMeigoEditFieldLabel_2 = uilabel(app.DECOMPOSITIONTab);
            app.PathMeigoEditFieldLabel_2.Text = 'Path Meigo';
            app.PathMeigoEditField = uieditfield(app.DECOMPOSITIONTab, 'text');
            app.PathMeigoEditField.ValueChangedFcn = createCallbackFcn(app, @PathMeigoEditFieldValueChanged, true);
            app.PathMeigoEditField.Value = 'C:\Users\xabor\Downloads\strike-goldd-dev\MEIGO';
            app.DECOMPOSITIONOPTIONSapplyonlytotheFISPOalgorithmLabel = uilabel(app.DECOMPOSITIONTab);
            app.DECOMPOSITIONOPTIONSapplyonlytotheFISPOalgorithmLabel.Text = 'DECOMPOSITION OPTIONS (apply only to the FISPO algorithm):';
            app.TabGroup2 = uitabgroup(app.DECOMPOSITIONTab);
            app.OptimizationbaseddescompositionTab = uitab(app.TabGroup2);
            app.OptimizationbaseddescompositionTab.Title = 'Optimization-based descomposition';
            app.IfoptimizationbaseddecompositionisusedLabel = uilabel(app.OptimizationbaseddescompositionTab);
            app.IfoptimizationbaseddecompositionisusedLabel.Text = 'If optimization-based decomposition is used:';
            app.MaximumtimeallowedforeveryoptimizationEditFieldLabel = uilabel(app.OptimizationbaseddescompositionTab);
            app.MaximumtimeallowedforeveryoptimizationEditFieldLabel.Text = 'Maximum time allowed for every optimization';
            app.MaxTimeAlloweOptEditField = uieditfield(app.OptimizationbaseddescompositionTab, 'numeric');
            app.MaxTimeAlloweOptEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTimeAlloweOptEditFieldValueChanged, true);
            app.MaxTimeAlloweOptEditField.Value = 30;
            app.MaximumnumberofstatesinthesubmodelsEditFieldLabel = uilabel(app.OptimizationbaseddescompositionTab);
            app.MaximumnumberofstatesinthesubmodelsEditFieldLabel.Text = 'Maximum  number of states in the submodels ';
            app.MaxNumStatesSubmEditField = uieditfield(app.OptimizationbaseddescompositionTab, 'numeric');
            app.MaxNumStatesSubmEditField.ValueChangedFcn = createCallbackFcn(app, @MaxNumStatesSubmEditFieldValueChanged, true);
            app.MaxNumStatesSubmEditField.Value = 6;
            app.DefinedbyuserTab = uitab(app.TabGroup2);
            app.DefinedbyuserTab.Title = 'Defined by user';
            app.DoyouwanttodescomposethemodelLabel_2 = uilabel(app.DefinedbyuserTab);
            app.DoyouwanttodescomposethemodelLabel_2.Text = 'Do you want to descompose the model?';
            app.Usesubmodelsspecifiedbytheuser1orfoundbyoptimization0Label = uilabel(app.DefinedbyuserTab);
            app.Usesubmodelsspecifiedbytheuser1orfoundbyoptimization0Label.Text = 'Use submodels specified by the user (= 1) or found by optimization (= 0)';
            app.submodelUserCheckBox = uicheckbox(app.DefinedbyuserTab);
            app.submodelUserCheckBox.ValueChangedFcn = createCallbackFcn(app, @submodelUserCheckBoxValueChanged, true);
            app.submodelUserCheckBox.Text = '';
            app.SubmodelsLabel = uilabel(app.DefinedbyuserTab);
            app.SubmodelsLabel.Text = 'Submodels:';
            app.Example12pressenter34pressenter523Label = uilabel(app.DefinedbyuserTab);
            app.Example12pressenter34pressenter523Label.Text = {'Example:'; '[1 2] (press enter)'; '[3 4] (press enter)'; '[5 2 3]'};
            app.submodelosTextArea = uitextarea(app.DefinedbyuserTab);
            app.submodelosTextArea.ValueChangedFcn = createCallbackFcn(app, @submodelosTextAreaValueChanged, true);
            app.submodelosTextArea.Value = {'[1 2]'; '[3 4]'; '[5 2 3]'};
            app.ForcedecompositionCheckBox = uicheckbox(app.DECOMPOSITIONTab);
            app.ForcedecompositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForcedecompositionCheckBoxValueChanged, true);
            app.ForcedecompositionCheckBox.Text = 'Force decomposition';
            app.OnlydecomposemodelifthewholemodelistoolargeLabel = uilabel(app.DECOMPOSITIONTab);
            app.OnlydecomposemodelifthewholemodelistoolargeLabel.Text = 'Only decompose model if the whole model is too large';
            app.OptionaldecompositionCheckBox = uicheckbox(app.DECOMPOSITIONTab);
            app.OptionaldecompositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptionaldecompositionCheckBoxValueChanged, true);
            app.OptionaldecompositionCheckBox.Text = 'Optional decomposition';
            app.TheseoptionsaredisabledforthisalgorithmLabel = uilabel(app.DECOMPOSITIONTab);
            app.TheseoptionsaredisabledforthisalgorithmLabel.Text = 'These options are disabled for this algorithm';
            app.MULTIEXPTab = uitab(app.TabGroup);
            app.MULTIEXPTab.Title = 'MULTIEXP.';
            app.MULTIEXPERIMENTOPTIONSapplytothe3SIOalgorithmsLabel = uilabel(app.MULTIEXPTab);
            app.MULTIEXPERIMENTOPTIONSapplytothe3SIOalgorithmsLabel.Text = 'MULTI-EXPERIMENT OPTIONS (apply to the 3 SIO algorithms)';
            app.ExecuteSwitch = uiswitch(app.MULTIEXPTab, 'toggle');
            app.ExecuteSwitch.Items = {'0', '1'};
            app.ExecuteSwitch.ValueChangedFcn = createCallbackFcn(app, @ExecuteSwitchValueChanged, true);
            app.ExecuteSwitch.Value = '0';
            app.ExecutemultiexperimentanalysisLabel = uilabel(app.MULTIEXPTab);
            app.ExecutemultiexperimentanalysisLabel.Text = 'Execute multi-experiment analysis?';
            app.TabGroup2_2 = uitabgroup(app.MULTIEXPTab);
            app.KnownunknowninputTab = uitab(app.TabGroup2_2);
            app.KnownunknowninputTab.Title = 'Known/unknown input';
            app.SetmanuallytheinitialconditionsforeachexperimentLabel_2 = uilabel(app.KnownunknowninputTab);
            app.SetmanuallytheinitialconditionsforeachexperimentLabel_2.Text = 'Set manually the number of non-zero known input derivatives in each experiment';
            app.knowMultiCheckBox = uicheckbox(app.KnownunknowninputTab);
            app.knowMultiCheckBox.ValueChangedFcn = createCallbackFcn(app, @knowMultiCheckBoxValueChanged, true);
            app.knowMultiCheckBox.Text = '';
            app.uknowCheckBox = uicheckbox(app.KnownunknowninputTab);
            app.uknowCheckBox.ValueChangedFcn = createCallbackFcn(app, @uknowCheckBoxValueChanged, true);
            app.uknowCheckBox.Text = '';
            app.SetmanuallytheinitialconditionsforeachexperimentLabel_4 = uilabel(app.KnownunknowninputTab);
            app.SetmanuallytheinitialconditionsforeachexperimentLabel_4.Text = 'Set manually the number of non-zero unknown input derivatives in each experiment';
            app.NumberofnonzeroknowninputderivativesineachexperimentsLabel = uilabel(app.KnownunknowninputTab);
            app.NumberofnonzeroknowninputderivativesineachexperimentsLabel.Text = {'Number of non-zero known input derivatives in each experiments:'; '(Rows = inputs; Columns = experiments)'};
            app.knowderivTextArea = uitextarea(app.KnownunknowninputTab);
            app.knowderivTextArea.ValueChangedFcn = createCallbackFcn(app, @knowderivTextAreaValueChanged, true);
            app.knowderivTextArea.Value = {'[0 1]'};
            app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_2 = uilabel(app.KnownunknowninputTab);
            app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_2.Text = {'Number of non-zero unknown input derivatives in each experiments:'; '(Rows = inputs; Columns = experiments)'};
            app.knowderivTextArea_2 = uitextarea(app.KnownunknowninputTab);
            app.knowderivTextArea_2.ValueChangedFcn = createCallbackFcn(app, @knowderivTextArea_2ValueChanged, true);
            app.knowderivTextArea_2.Value = {'[0 1]'};
            app.InitialconditionsTab = uitab(app.TabGroup2_2);
            app.InitialconditionsTab.Title = 'Initial conditions';
            app.SetmanuallytheinitialconditionsforeachexperimentLabel = uilabel(app.InitialconditionsTab);
            app.SetmanuallytheinitialconditionsforeachexperimentLabel.Text = 'Set manually the initial conditions for each experiment';
            app.ICsMultCheckBox = uicheckbox(app.InitialconditionsTab);
            app.ICsMultCheckBox.ValueChangedFcn = createCallbackFcn(app, @ICsMultCheckBoxValueChanged, true);
            app.ICsMultCheckBox.Text = '';
            app.TabGroup3 = uitabgroup(app.InitialconditionsTab);
            app.WhichTab = uitab(app.TabGroup3);
            app.WhichTab.Title = 'Which';
            app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_7 = uilabel(app.WhichTab);
            app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_7.Text = {'Which initial conditions are replaced?              '; 'Columns: variables                    Rows: experiments'};
            app.wichicsMultiTextArea = uitextarea(app.WhichTab);
            app.wichicsMultiTextArea.ValueChangedFcn = createCallbackFcn(app, @wichicsMultiTextAreaValueChanged, true);
            app.wichicsMultiTextArea.Value = {'[0,1,1,0,1,0,1,1,1]'; '[0,1,1,0,1,0,1,1,1]'};
            app.ICsTab = uitab(app.TabGroup3);
            app.ICsTab.Title = 'ICs';
            app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_6 = uilabel(app.ICsTab);
            app.NumberofnonzeroknowninputderivativesineachexperimentsLabel_6.Text = {'Multi-experiment initial conditions                 '; 'Columns: variables    '; 'Rows: experiments'};
            app.InitialconditionsLabel_2 = uilabel(app.ICsTab);
            app.InitialconditionsLabel_2.Text = 'Initial conditions:';
            app.icsMultiTextArea = uitextarea(app.ICsTab);
            app.icsMultiTextArea.ValueChangedFcn = createCallbackFcn(app, @icsMultiTextAreaValueChanged, true);
            app.icsMultiTextArea.Value = {'[1,0,0,1,0,1,0,0,0]'; '[1,0,0,1,0,1,0,0,0]'};
            app.NumberofexperimentsEditFieldLabel = uilabel(app.MULTIEXPTab);
            app.NumberofexperimentsEditFieldLabel.Text = 'Number of experiments';
            app.NumberofexperimentsEditField = uieditfield(app.MULTIEXPTab, 'numeric');
            app.NumberofexperimentsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofexperimentsEditFieldValueChanged, true);
            app.NumberofexperimentsEditField.Value = 2;
            app.TheseoptionsaredisabledforthisalgorithmLabel_2 = uilabel(app.MULTIEXPTab);
            app.TheseoptionsaredisabledforthisalgorithmLabel_2.Text = 'These options are disabled for this algorithm';
            app.ORCDFTab = uitab(app.TabGroup);
            app.ORCDFTab.Title = 'ORC DF';
            app.AFFINEOPTIONSapplyonlytotheORCDFalgorithmLabel = uilabel(app.ORCDFTab);
            app.AFFINEOPTIONSapplyonlytotheORCDFalgorithmLabel.Text = 'AFFINE OPTIONS (apply only to the ORC-DF algorithm):';
            app.MaxcomputationtimeforthelastiterationEditFieldLabel = uilabel(app.ORCDFTab);
            app.MaxcomputationtimeforthelastiterationEditFieldLabel.Text = 'Max. computation time for the last iteration';
            app.MaxTimeEditField = uieditfield(app.ORCDFTab, 'numeric');
            app.MaxTimeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTimeEditFieldValueChanged, true);
            app.MaxTimeEditField.Value = 1000;
            app.MaxnumberofiterationsEditFieldLabel = uilabel(app.ORCDFTab);
            app.MaxnumberofiterationsEditFieldLabel.Text = 'Max. number of iterations';
            app.MaxNumItEditField = uieditfield(app.ORCDFTab, 'numeric');
            app.MaxNumItEditField.ValueChangedFcn = createCallbackFcn(app, @MaxNumItEditFieldValueChanged, true);
            app.MaxNumItEditField.Value = 4;
            app.PARALLELOPTIONSLabel = uilabel(app.ORCDFTab);
            app.PARALLELOPTIONSLabel.Text = 'PARALLEL OPTIONS:';
            app.ParalORCCheckBox = uicheckbox(app.ORCDFTab);
            app.ParalORCCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParalORCCheckBoxValueChanged, true);
            app.ParalORCCheckBox.Text = 'Use parallel toolbox to calculate partial ranks';
            app.NumberofworkersforparallelpoolLabel = uilabel(app.ORCDFTab);
            app.NumberofworkersforparallelpoolLabel.Text = 'Number of workers for parallel pool:';
            app.NumWParEditField = uieditfield(app.ORCDFTab, 'numeric');
            app.NumWParEditField.ValueChangedFcn = createCallbackFcn(app, @NumWParEditFieldValueChanged, true);
            app.NumWParEditField.Value = 4;
            app.DisplaygraphicsCheckBox = uicheckbox(app.ORCDFTab);
            app.DisplaygraphicsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplaygraphicsCheckBoxValueChanged, true);
            app.DisplaygraphicsCheckBox.Text = 'Display graphics';
            app.DisplaygraphicsCheckBox.Value = true;
            app.DelModelORCCheckBox = uicheckbox(app.ORCDFTab);
            app.DelModelORCCheckBox.ValueChangedFcn = createCallbackFcn(app, @DelModelORCCheckBoxValueChanged, true);
            app.DelModelORCCheckBox.Text = 'Delete affine model when finished';
            app.DelModelORCCheckBox.Value = true;
            app.TheseoptionsaredisabledforthisalgorithmLabel_3 = uilabel(app.ORCDFTab);
            app.TheseoptionsaredisabledforthisalgorithmLabel_3.Text = 'These options are disabled for this algorithm';
            app.LIESYMMETRIESTab = uitab(app.TabGroup);
            app.LIESYMMETRIESTab.Title = 'LIE SYMMETRIES';
            app.LIESYMMETRIESREPARAMETERIZATIONOPTIONSLabel = uilabel(app.LIESYMMETRIESTab);
            app.LIESYMMETRIESREPARAMETERIZATIONOPTIONSLabel.Text = 'LIE SYMMETRIES & REPARAMETERIZATION OPTIONS';
            app.onlyforLie_SymmetryAutoReparLabel = uilabel(app.LIESYMMETRIESTab);
            app.onlyforLie_SymmetryAutoReparLabel.Text = '(only for Lie_Symmetry, AutoRepar)';
            app.Univariate1Label = uilabel(app.LIESYMMETRIESTab);
            app.Univariate1Label.Text = 'Univariate (1)';
            app.Partiallyvariate2Label = uilabel(app.LIESYMMETRIESTab);
            app.Partiallyvariate2Label.Text = 'Partially variate (2)';
            app.Multivariate3Label = uilabel(app.LIESYMMETRIESTab);
            app.Multivariate3Label.Text = 'Multivariate (3)';
            app.TypeofAnsatzEditFieldLabel = uilabel(app.LIESYMMETRIESTab);
            app.TypeofAnsatzEditFieldLabel.Text = 'Type of Ansatz';
            app.TypeAnsatzEditField = uieditfield(app.LIESYMMETRIESTab, 'numeric');
            app.TypeAnsatzEditField.ValueChangedFcn = createCallbackFcn(app, @TypeAnsatzEditFieldValueChanged, true);
            app.TypeAnsatzEditField.Value = 2;
            app.DegreeofAnsatzpolynomialEditFieldLabel = uilabel(app.LIESYMMETRIESTab);
            app.DegreeofAnsatzpolynomialEditFieldLabel.Text = 'Degree of Ansatz polynomial';
            app.DegAnsatzEditField = uieditfield(app.LIESYMMETRIESTab, 'numeric');
            app.DegAnsatzEditField.ValueChangedFcn = createCallbackFcn(app, @DegAnsatzEditFieldValueChanged, true);
            app.DegAnsatzEditField.Value = 2;
            app.MaximumdegreeofLieseriesEditFieldLabel = uilabel(app.LIESYMMETRIESTab);
            app.MaximumdegreeofLieseriesEditFieldLabel.Text = 'Maximum degree of Lie series';
            app.MaxDegLieserEditField = uieditfield(app.LIESYMMETRIESTab, 'numeric');
            app.MaxDegLieserEditField.ValueChangedFcn = createCallbackFcn(app, @MaxDegLieserEditFieldValueChanged, true);
            app.MaxDegLieserEditField.Value = 4;
            app.solverLieCheckBox = uicheckbox(app.LIESYMMETRIESTab);
            app.solverLieCheckBox.ValueChangedFcn = createCallbackFcn(app, @solverLieCheckBoxValueChanged, true);
            app.solverLieCheckBox.Text = 'Use ode solver (only in Matlab R2020a and later';
            app.solverLieCheckBox.Value = true;
            app.existResultsCheckBox = uicheckbox(app.LIESYMMETRIESTab);
            app.existResultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @existResultsCheckBoxValueChanged, true);
            app.existResultsCheckBox.Text = 'Use existing results if the model has already been analysed';
            app.PathEditFieldLabel = uilabel(app.LIESYMMETRIESTab);
            app.PathEditFieldLabel.Text = 'Path:';
            app.PathEditField = uieditfield(app.LIESYMMETRIESTab, 'text');
            app.PathEditField.ValueChangedFcn = createCallbackFcn(app, @PathEditFieldValueChanged, true);
            app.PathEditField.Value = 'id_results_C2M_11-May-2022';
            app.TheseoptionsaredisabledforthisalgorithmLabel_4 = uilabel(app.LIESYMMETRIESTab);
            app.TheseoptionsaredisabledforthisalgorithmLabel_4.Text = 'These options are disabled for this algorithm';
            app.AlgorithmDropDownLabel = uilabel(app.SG);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.SG);
            app.AlgorithmDropDown.Items = {'Select algorithm', 'FISPO', 'Prob_Obs_Test', 'ORC DF', 'Lie_Symmetry', 'AutoRepar'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Value = 'Select algorithm';
            app.RunButton = uibutton(app.SG, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Lamp = uilamp(app.SG);
            app.CloseappButton = uibutton(app.SG, 'push');
            app.CloseappButton.ButtonPushedFcn = createCallbackFcn(app, @CloseappButtonPushed, true);
            app.CloseappButton.Text = 'Close app';
            app.SaveoptionsfileButton = uibutton(app.SG, 'push');
            app.SaveoptionsfileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveoptionsfileButtonPushed, true);
            app.SaveoptionsfileButton.Text = 'Save options file';
            app.ButtonGroup = uibuttongroup(app.SG);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.USEEXISTINGMODELButton = uitogglebutton(app.ButtonGroup);
            app.USEEXISTINGMODELButton.Text = 'USE EXISTING MODEL';
            app.USEEXISTINGMODELButton.Value = true;
            app.CREATENEWMODELButton = uitogglebutton(app.ButtonGroup);
            app.CREATENEWMODELButton.Text = 'CREATE NEW MODEL';
            app.ModelnameDropDown = uidropdown(app.SG);
            app.ModelnameDropDown.Items = {'Models in the folder'};
            app.ModelnameDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ModelnameDropDownOpening, true);
            app.ModelnameDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelnameDropDownValueChanged, true);
            app.ModelnameDropDown.Value = 'Models in the folder';
            app.ModelnameEditFieldLabel_2 = uilabel(app.SG);
            app.ModelnameEditFieldLabel_2.Text = 'Model name';
            % Show the figure after all components are created
            app.SG.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STRIKE_GOLDD_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SG)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SG)
        end
    end
end