classdef NLControllability_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NLCont                          matlab.ui.Figure
        STRIKEGOLDDPanel                matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        MaxLieTimeEditField             matlab.ui.control.NumericEditField
        MaximumtimefortheeachtestinsecondsLabel  matlab.ui.control.Label
        linearNumeric                   matlab.ui.control.CheckBox
        ModelnameEditFieldLabel_2       matlab.ui.control.Label
        ModelnameDropDown_2             matlab.ui.control.DropDown
        RUNButton                       matlab.ui.control.Button
        SussmannsConditionCheckBox      matlab.ui.control.CheckBox
        LieAlgebraicRankConditionCheckBox  matlab.ui.control.CheckBox
        AccesibilityRankConditionCheckBox  matlab.ui.control.CheckBox
        LinearizationConditionCheckBox  matlab.ui.control.CheckBox
        CONTROLLABILITYANDACCESSIBILITYLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        LinearizationYes % Description
        Modelnameselect % Description
        ARC % Description
        LieARC % Description
        sussmann % Description
        namefiles1 % Description
        linearNumeric1 % Description
        timeLinear % Description
        oldpath1 % Description
    end
    
    methods (Access = private)
        %%% DIALOG BOX TO CLOSE THE APP
        function mycallback(app,src,event)
            if event.SelectedOption == "OK"
                %delete('options_aux.m')
                delete(app.NLCont);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.oldpath1 = pwd;            
            
            %rutafinal = app.oldpath(end-32:end);
            %comparar = strcmp(rutafinal,'\strike-goldd-master\STRIKE-GOLDD');
            
            rutafinal = app.oldpath1(end-12:end);
            comparar = strcmp(rutafinal,'\STRIKE-GOLDD');
            
            optsMes = struct('WindowStyle','modal',... 
              'Interpreter','tex');
            if comparar == 0
                uiwait(warndlg([ '\fontsize{10} You should run the app from \it STRIKE-GOLDD \rm folder. Press OK ' ...
                    'to close the APP and to change the Current folder'],'Error in path',optsMes));
                
                delete(app.SG);
                return
            end
            
            addpath(genpath('models'));
            addpath(genpath('functions'));

            %%% LISTA DESPLEGABLE %%%%%%%%%%%%%%%%%%%%%%%%
            %(Leemos los archivos .mat e incluimos los nombres dentro 
            % de una variable para la lista desplegable)
            SGfolder = cd;
            cd models
            
            Files=dir('*.mat*');
            for k=1:length(Files)
               FileNames{k}=Files(k).name;
            end
                        
            for i = 1:length(FileNames) 
                app.namefiles1{i} = erase(FileNames{i},'.mat');
            end
            
            cd(SGfolder)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Close request function: NLCont
        function NLContCloseRequest(app, event)
            uiconfirm(app.NLCont,'Are you sure you wanto to close STRIKE-GOLDD?','Confirm Close', ...
            'CloseFcn',@app.mycallback);
        end

        % Value changed function: LinearizationConditionCheckBox
        function LinearizationConditionCheckBoxValueChanged(app, event)
            %%% by default
            % app.LinearizationConditionCheckBox.Value = 1            
            
            app.LinearizationYes = app.LinearizationConditionCheckBox.Value;
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.Lamp.Color = 'red';
            drawnow 
            %Ensure values are in the properties:
            app.LinearizationYes = app.LinearizationConditionCheckBox.Value;
            app.ARC = app.AccesibilityRankConditionCheckBox.Value;
            app.LieARC = app.LieAlgebraicRankConditionCheckBox.Value;
            app.sussmann = app.SussmannsConditionCheckBox.Value;
            app.timeLinear = app.MaxLieTimeEditField.Value;
            app.linearNumeric1 = app.linearNumeric.Value;
            app.Modelnameselect = app.ModelnameDropDown_2.Value;
            
            opcionesBasicas = [app.LinearizationYes,app.ARC,app.LieARC,app.sussmann];
            modelo = app.Modelnameselect;

            uialert(app.NLCont,['The analysis ends when the Run button turns green (press OK to close this message)'],'Strike-Goldd');

            ctrl_analysis_MAIN(opcionesBasicas,modelo, app.linearNumeric1, app.timeLinear); 
            drawnow
            app.Lamp.Color = 'g';

        end

        % Value changed function: AccesibilityRankConditionCheckBox
        function AccesibilityRankConditionCheckBoxValueChanged(app, event)
            %%% by default
            % app.AccesibilityRankConditionCheckBox.Value = 1            
            
            app.ARC = app.AccesibilityRankConditionCheckBox.Value;
            
        end

        % Value changed function: LieAlgebraicRankConditionCheckBox
        function LieAlgebraicRankConditionCheckBoxValueChanged(app, event)
            %%% by default
            % app.LieAlgebraicRankConditionCheckBox.Value = 1            

            app.LieARC = app.LieAlgebraicRankConditionCheckBox.Value;
            
        end

        % Value changed function: SussmannsConditionCheckBox
        function SussmannsConditionCheckBoxValueChanged(app, event)
            %%% by default
            % app.SussmannsConditionCheckBox.Value = 1            
            
            app.sussmann = app.SussmannsConditionCheckBox.Value;
            
        end

        % Value changed function: ModelnameDropDown_2
        function ModelnameDropDown_2ValueChanged(app, event)
            app.Modelnameselect = app.ModelnameDropDown_2.Value;

        end

        % Drop down opening function: ModelnameDropDown_2
        function ModelnameDropDown_2Opening(app, event)
            app.ModelnameDropDown_2.Items = app.namefiles1;
        end

        % Value changed function: linearNumeric
        function linearNumericValueChanged(app, event)
            %By default: 0
            app.linearNumeric1 = app.linearNumeric.Value;
        end

        % Value changed function: MaxLieTimeEditField
        function MaxLieTimeEditFieldValueChanged(app, event)
            %By default: 10 seconds
            app.timeLinear = app.MaxLieTimeEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NLCont = uifigure('Visible', 'off');
            app.NLCont.Name = 'MATLAB App';
            app.NLCont.CloseRequestFcn = createCallbackFcn(app, @NLContCloseRequest, true);
            app.CONTROLLABILITYANDACCESSIBILITYLabel = uilabel(app.NLCont);
            app.CONTROLLABILITYANDACCESSIBILITYLabel.Text = 'CONTROLLABILITY AND ACCESSIBILITY';
            app.LinearizationConditionCheckBox = uicheckbox(app.NLCont);
            app.LinearizationConditionCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinearizationConditionCheckBoxValueChanged, true);
            app.LinearizationConditionCheckBox.Tag = 'lineariz';
            app.LinearizationConditionCheckBox.Text = 'Do you want to check the linearization condition for your system or model?';
            app.LinearizationConditionCheckBox.Value = true;
            app.AccesibilityRankConditionCheckBox = uicheckbox(app.NLCont);
            app.AccesibilityRankConditionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AccesibilityRankConditionCheckBoxValueChanged, true);
            app.AccesibilityRankConditionCheckBox.Text = 'Do you want to check the Accesibility Rank Condition?';
            app.AccesibilityRankConditionCheckBox.Value = true;
            app.LieAlgebraicRankConditionCheckBox = uicheckbox(app.NLCont);
            app.LieAlgebraicRankConditionCheckBox.ValueChangedFcn = createCallbackFcn(app, @LieAlgebraicRankConditionCheckBoxValueChanged, true);
            app.LieAlgebraicRankConditionCheckBox.Text = 'Do you want to check the Lie Algebraic Rank Condition?';
            app.LieAlgebraicRankConditionCheckBox.Value = true;
            app.SussmannsConditionCheckBox = uicheckbox(app.NLCont);
            app.SussmannsConditionCheckBox.ValueChangedFcn = createCallbackFcn(app, @SussmannsConditionCheckBoxValueChanged, true);
            app.SussmannsConditionCheckBox.Text = 'Do you want to check Sussmann''s General Sufficient Condition?';
            app.SussmannsConditionCheckBox.Value = true;
            app.RUNButton = uibutton(app.NLCont, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.ModelnameDropDown_2 = uidropdown(app.NLCont);
            app.ModelnameDropDown_2.Items = {'Models in the folder'};
            app.ModelnameDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @ModelnameDropDown_2Opening, true);
            app.ModelnameDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ModelnameDropDown_2ValueChanged, true);
            app.ModelnameDropDown_2.Value = 'Models in the folder';
            app.ModelnameEditFieldLabel_2 = uilabel(app.NLCont);
            app.ModelnameEditFieldLabel_2.Text = 'Model name:';
            app.linearNumeric = uicheckbox(app.NLCont);
            app.linearNumeric.ValueChangedFcn = createCallbackFcn(app, @linearNumericValueChanged, true);
            app.linearNumeric.Text = 'Check the Linearization Condition numerically, otherwise simbolically';
            app.MaximumtimefortheeachtestinsecondsLabel = uilabel(app.NLCont);
            app.MaximumtimefortheeachtestinsecondsLabel.Text = 'Maximum time for the each test in seconds';
            app.MaxLieTimeEditField = uieditfield(app.NLCont, 'numeric');
            app.MaxLieTimeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxLieTimeEditFieldValueChanged, true);
            app.MaxLieTimeEditField.Value = 10;
            app.Lamp = uilamp(app.NLCont);
            app.STRIKEGOLDDPanel = uipanel(app.NLCont);
            app.STRIKEGOLDDPanel.Title = 'STRIKE GOLDD';
            % Show the figure after all components are created
            app.NLCont.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLControllability_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NLCont)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NLCont)
        end
    end
end