classdef newmodel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SymbolicinitialconditionsCheckBox  matlab.ui.control.CheckBox
        UnknowninputEditField       matlab.ui.control.EditField
        UnknowninputEditFieldLabel  matlab.ui.control.Label
        KnowninputEditField         matlab.ui.control.EditField
        KnowninputEditFieldLabel    matlab.ui.control.Label
        OutputEditField             matlab.ui.control.EditField
        OutputLabel                 matlab.ui.control.Label
        CreatemodelButton           matlab.ui.control.Button
        InitialconditionsEditField  matlab.ui.control.EditField
        InitialconditionsEditFieldLabel  matlab.ui.control.Label
        EquationsLabel              matlab.ui.control.Label
        eqns                        matlab.ui.control.TextArea
        ParametersEditField         matlab.ui.control.EditField
        ParametersLabel             matlab.ui.control.Label
        StatesEditField             matlab.ui.control.EditField
        StatesLabel                 matlab.ui.control.Label
        ModelnameEditField          matlab.ui.control.EditField
        ModelnameEditFieldLabel     matlab.ui.control.Label
        NewmodelPanel               matlab.ui.container.Panel
    end

    
    properties (Access = private)
        states % Description
        parameters % Description
        equations % Description
        outputY % Description
        ics % Description
        inputk % Description
        inputu % Description
        symCI % Description
    end
    
    properties (Access = public)
        namemodel % Description
    end
    
    methods (Access = private)
        %%% DIALOG BOX TO CLOSE THE APP
        function mycallback(app,src,event)
            if event.SelectedOption == "OK"
                delete(app.UIFigure);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, STRIKEGOLDD)
            
        end

        % Value changed function: ModelnameEditField
        function ModelnameEditFieldValueChanged(app, event)
           
            app.namemodel = app.ModelnameEditField.Value;
            
        end

        % Button pushed function: CreatemodelButton
        function CreatemodelButtonPushed(app, event)
            %WAITT
            
            
            %Initialize variable values
            app.namemodel = app.ModelnameEditField.Value;
            app.states = app.StatesEditField.Value;
            app.parameters = app.ParametersEditField.Value;
            app.equations = app.eqns.Value;
            app.ics = app.InitialconditionsEditField.Value;
            app.outputY =  app.OutputEditField.Value;
            app.inputu = app.UnknowninputEditField.Value;
            app.inputk = app.KnowninputEditField.Value;
            app.symCI = app.SymbolicinitialconditionsCheckBox.Value;


            actualpath = pwd;
            
            %%%
            SGfolder = cd;
            cd 'functions\aux_APP'
            
            
            fod = fopen('CreateNewModel.m', 'w');
            
            fprintf(fod,'function CreateNewModel()\n');
            fprintf(fod,'clear all\n');
            fprintf(fod,'syms '); 

            %%

            %%%%% CONDICIONES ICS SIMBÓLICAS

            if app.symCI == 1
                symcis = replace(app.ics,{','},{' '});  
                symcis = erase(symcis,{'[',']'});
                symcis = split(symcis);

                %Recorro los elementos del cell array
                for i = 1:length(symcis)
                    %Recorro las letras/números de cada elemento
                    for j = 1:length(symcis{i})

                        if  isstrprop( symcis{i}(j), 'alpha' )
                            symbVar{i} = symcis{i};
                            break
                        end
                        
                    end

                end
                for i = 1:length(symbVar)
                    fprintf(fod,' %s ',symbVar{i});
                end
            end


            %%%%% STATES
            app.states = strrep(app.states, ',', ' ');
            app.states = split(app.states); %Delete substrings within strings
            b = app.states;
            for i = 1:length(b)
                par = char(b(i));
                fprintf(fod,' %s',par);
            end

            fprintf(fod,'\n');
            fprintf(fod,'x = transpose([');
            fprintf(fod,'%s',char(b(1)));
            for i = 2:length(b)
                par = char(b(i));
                fprintf(fod,' %s',par);
            end            
            fprintf(fod, ']);\n');
            
            
            %%%%% PARAMETERS
            app.parameters = strrep(app.parameters, ',', ' ');
            app.parameters = split(app.parameters);
            pp = app.parameters;
            fprintf(fod,'syms'); 
            for i = 1:length(pp)
                par = char(pp(i));
                fprintf(fod,' %s',par);
            end

            fprintf(fod,'\n');
            fprintf(fod,'p = transpose([');
            fprintf(fod,'%s',char(pp(1)));
            for i = 2:length(pp)
                par = char(pp(i));
                fprintf(fod,' %s',par);
            end            
            fprintf(fod, ']);\n');   
            
            %%%%% OUTPUTS
            fprintf(fod, 'h = transpose([');
            fprintf(fod,'%s',app.outputY);
            fprintf(fod,']);\n');
            
            %%%%% INPUTS 
            clear b
            % known
            if app.inputk ~= ""
                app.inputk = strrep(app.inputk, ',', ' ');
                app.inputk = split(app.inputk);
                fprintf(fod,'syms');
                b = app.inputk;
                for i = 1:length(b)
                    par = char(b(i));
                    fprintf(fod,' %s',par);
                end

                fprintf(fod,'\n');
                fprintf(fod,'u = [');
                fprintf(fod,'%s',char(b(1)));
                for i = 2:length(b)
                    par = char(b(i));
                    fprintf(fod,' %s',par);
                end            
                fprintf(fod, '];\n');
            else
                fprintf(fod,'u = [];');
            end
            % unknown
            if app.inputu ~= ""
                clear b
                app.inputu = strrep(app.inputu, ',', ' ');
                app.inputu = split(app.inputu);
                fprintf(fod,'syms');
                b = app.inputu;
                for i = 1:length(b)
                    par = char(b(i));
                    fprintf(fod,' %s',par);
                end
    
                fprintf(fod,'\n');
                fprintf(fod,'w = [');
                fprintf(fod,'%s',char(b(1)));
                for i = 2:length(b)
                    par = char(b(i));
                    fprintf(fod,' %s',par);
                end            
                fprintf(fod, '];\n');   
            else
                fprintf(fod,'w = [];');
            end
            
            %%%%% EQUATIONS
            fprintf(fod,'f = [');
            b = app.equations;
            for i = 1:length(b)-1
                par = char(b(i));
                fprintf(fod,' %s\n',par);
            end            
            fprintf(fod,'%s];\n',b{end});
            
            %%%%% INITIAL CONDITIONS
            
            if app.ics == ""
                knIcs = zeros(1,length(app.states));
                fprintf(fod,'known_ics = [');
                for i = 1:length(knIcs)
                    fprintf(fod,'%i ',knIcs(i));
                end
                fprintf(fod,'];\n');
                app.ics = zeros(1,length(app.states));
                fprintf(fod,'ics = [');
                for i = 1:length(app.ics)
                    fprintf(fod,'%i ',app.ics(i));
                end
                fprintf(fod,'];\n');
            else               
                app.ics = replace(app.ics,{','},{' '});
                app.ics = erase(app.ics,{'[',']'});
                app.ics = split(app.ics);
                
                knIcs = ones(1,length(app.ics));
                for i = 1:length(app.ics)
                    if isnan(app.ics{i})
                        knIcs(i) = 0;
                    end
                end

                fprintf(fod,'ics  = [');
                fprintf(fod,'%s',char(app.ics(1)));
                for i = 2:length(app.ics)
                    par = char(app.ics(i));
                    fprintf(fod,' %s',par);
                end            
                fprintf(fod, '];\n');  
                
                fprintf(fod,'known_ics = [');
                for i = 1:length(knIcs)
                    fprintf(fod,'%i ',knIcs(i));
                end
                fprintf(fod,'];\n');
            end
            
            paths.models    = strcat(actualpath,filesep,'models');
            paths.results   = strcat(actualpath,filesep,'results');
            paths.functions = strcat(actualpath,filesep,'functions');
            pat = strcat(paths.models,'\',app.namemodel);
            fprintf(fod,'save("%s"',pat);
            fprintf(fod,',"x","p","u","w","h","f","ics","known_ics");\n');
            fprintf(fod,'end');
            
            fclose(fod);

            cd(SGfolder)
            
            message = 'Wait, the model is still being written...';
            message1 = [message newline 'The window will close when the APP has finished the model'];
            %uialert(app.UIFigure,message1,'Newmodel');
            uialert(app.UIFigure,message,'Newmodel');
            

            CreateNewModel();
            
            
            uiconfirm(app.UIFigure,'Did you finalise the model?','Confirm Close', ...
            'CloseFcn',@app.mycallback); 
            
        end

        % Value changed function: StatesEditField
        function StatesEditFieldValueChanged(app, event)
            
            app.states = app.StatesEditField.Value;
            
        end

        % Value changed function: ParametersEditField
        function ParametersEditFieldValueChanged(app, event)
            
            app.parameters = app.ParametersEditField.Value;
            
        end

        % Value changed function: eqns
        function eqnsValueChanged(app, event)
            app.equations = app.eqns.Value;
            
        end

        % Value changed function: InitialconditionsEditField
        function InitialconditionsEditFieldValueChanged(app, event)
            app.ics = app.InitialconditionsEditField.Value;
            
        end

        % Value changed function: OutputEditField
        function OutputEditFieldValueChanged(app, event)
            app.outputY = app.OutputEditField.Value;
            
        end

        % Value changed function: KnowninputEditField
        function KnowninputEditFieldValueChanged(app, event)
            app.inputk = app.KnowninputEditField.Value;
            
        end

        % Value changed function: UnknowninputEditField
        function UnknowninputEditFieldValueChanged(app, event)
            app.inputu = app.UnknowninputEditField.Value;
            
        end

        % Value changed function: SymbolicinitialconditionsCheckBox
        function SymbolicinitialconditionsCheckBoxValueChanged(app, event)
            app.symCI = app.SymbolicinitialconditionsCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NewmodelPanel = uipanel(app.UIFigure);
            app.NewmodelPanel.Title = 'New model';
            app.ModelnameEditFieldLabel = uilabel(app.UIFigure);
            app.ModelnameEditFieldLabel.Text = 'Model name';
            app.ModelnameEditField = uieditfield(app.UIFigure, 'text');
            app.ModelnameEditField.ValueChangedFcn = createCallbackFcn(app, @ModelnameEditFieldValueChanged, true);
            app.ModelnameEditField.Value = 'EXAMPLE';
            app.StatesLabel = uilabel(app.UIFigure);
            app.StatesLabel.Text = 'States:';
            app.StatesEditField = uieditfield(app.UIFigure, 'text');
            app.StatesEditField.ValueChangedFcn = createCallbackFcn(app, @StatesEditFieldValueChanged, true);
            app.StatesEditField.Value = 'x1 x2 x3';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters:';
            app.ParametersEditField = uieditfield(app.UIFigure, 'text');
            app.ParametersEditField.ValueChangedFcn = createCallbackFcn(app, @ParametersEditFieldValueChanged, true);
            app.ParametersEditField.Value = 'p1, p2, p3, p4, p5';
            app.eqns = uitextarea(app.UIFigure);
            app.eqns.ValueChangedFcn = createCallbackFcn(app, @eqnsValueChanged, true);
            app.eqns.Value = {'p1 - p2*x1 - u1*x1*x3;'; 'u1*x1*x3 - p4*x2;'; 'p3*p4*x2 - p5*x3'};
            app.EquationsLabel = uilabel(app.UIFigure);
            app.EquationsLabel.Text = 'Equations:';
            app.InitialconditionsEditFieldLabel = uilabel(app.UIFigure);
            app.InitialconditionsEditFieldLabel.Text = 'Initial conditions';
            app.InitialconditionsEditField = uieditfield(app.UIFigure, 'text');
            app.InitialconditionsEditField.ValueChangedFcn = createCallbackFcn(app, @InitialconditionsEditFieldValueChanged, true);
            app.InitialconditionsEditField.Value = '[0,0,0]';
            app.CreatemodelButton = uibutton(app.UIFigure, 'push');
            app.CreatemodelButton.ButtonPushedFcn = createCallbackFcn(app, @CreatemodelButtonPushed, true);
            app.CreatemodelButton.Text = 'Create model';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output:';
            app.OutputEditField = uieditfield(app.UIFigure, 'text');
            app.OutputEditField.ValueChangedFcn = createCallbackFcn(app, @OutputEditFieldValueChanged, true);
            app.OutputEditField.Value = 'x3, x2+x1';
            app.KnowninputEditFieldLabel = uilabel(app.UIFigure);
            app.KnowninputEditFieldLabel.Text = 'Known input:';
            app.KnowninputEditField = uieditfield(app.UIFigure, 'text');
            app.KnowninputEditField.ValueChangedFcn = createCallbackFcn(app, @KnowninputEditFieldValueChanged, true);
            app.KnowninputEditField.Value = 'u1';
            app.UnknowninputEditFieldLabel = uilabel(app.UIFigure);
            app.UnknowninputEditFieldLabel.Text = 'Unknown input:';
            app.UnknowninputEditField = uieditfield(app.UIFigure, 'text');
            app.UnknowninputEditField.ValueChangedFcn = createCallbackFcn(app, @UnknowninputEditFieldValueChanged, true);
            app.SymbolicinitialconditionsCheckBox = uicheckbox(app.UIFigure);
            app.SymbolicinitialconditionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SymbolicinitialconditionsCheckBoxValueChanged, true);
            app.SymbolicinitialconditionsCheckBox.Text = 'Symbolic initial conditions';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newmodel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end