classdef prevPars < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        knownParsUIFigure   matlab.ui.Figure
        ReadyButton         matlab.ui.control.Button
        submodelosTextArea  matlab.ui.control.TextArea
        ParametersassumedknownoralreadyclassifiedasidentifiableLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        params % Description
    end
    
    methods (Access = private)
        %%% DIALOG BOX TO CLOSE THE APP
        function mycallback(app,src,event)
            if event.SelectedOption == "OK"
                delete(app.knownParsUIFigure);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: submodelosTextArea
        function submodelosTextAreaValueChanged(app, event)
            %value: cell array of chars
            value = app.submodelosTextArea.Value;
            app.params = value;
            
        end

        % Button pushed function: ReadyButton
        function ReadyButtonPushed(app, event)
            %syms
            %close all
            app.params=app.submodelosTextArea.Value;
            b = app.params;
            
            %delete('prevIdPars.m')

            check = 0;
            for i = 1:length(b) 
                bb = b{i};
                if length(bb)>2
                    for j=2:length(bb)-1
                        anterior = bb(j-1);
                        actual = bb(j);
                        siguiente = bb(j+1);
                        if actual == ' ' && anterior ~= ' ' && siguiente ~= ' '
                            check = 1;
                            break
                        end
                    end
                end
                if check == 1
                    break
                end
            end
            
            if check == 1

                uialert(app.knownParsUIFigure,'Parameters must be listed vertically', ...
                    'Invalid List');   
            
            else    
                SGfolder = cd;
                cd 'functions\aux_APP'
                fid = fopen('prevIdPars.m', 'w');
                fprintf(fid,'function prev_pars = prevIdPars()\n');
                fprintf(fid,'syms');  
                for i = 1:length(b)
                    par = char(b(i));
                    fprintf(fid,' %s',par);
                end
                
                fprintf(fid,'\n');
                fprintf(fid,'prev_pars = [');
                fprintf(fid,'%s',char(b(1)));
                for i = 2:length(b)
                    par = char(b(i));
                    fprintf(fid,' %s',par);
                end            
                fprintf(fid, '];\n');
                fprintf(fid,'end');
                
                fclose(fid);
                cd(SGfolder)
                         
                uiconfirm(app.knownParsUIFigure,'Did you finalise the list?','Confirm Close', ...
                'CloseFcn',@app.mycallback); 
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.knownParsUIFigure = uifigure('Visible', 'off');
            app.knownParsUIFigure.Name = 'knownPars';
            app.ParametersassumedknownoralreadyclassifiedasidentifiableLabel = uilabel(app.knownParsUIFigure);
            app.ParametersassumedknownoralreadyclassifiedasidentifiableLabel.Text = 'Vertical list of parameters assumed known, or already classified as identifiable';
            app.submodelosTextArea = uitextarea(app.knownParsUIFigure);
            app.submodelosTextArea.ValueChangedFcn = createCallbackFcn(app, @submodelosTextAreaValueChanged, true);
            app.ReadyButton = uibutton(app.knownParsUIFigure, 'push');
            app.ReadyButton.ButtonPushedFcn = createCallbackFcn(app, @ReadyButtonPushed, true);
            app.ReadyButton.Text = 'Ready';
            % Show the figure after all components are created
            app.knownParsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prevPars

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.knownParsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.knownParsUIFigure)
        end
    end
end